classdef FeatureExtraction_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uipanel2              matlab.ui.container.Panel
        text2                 matlab.ui.control.Label
        SignalToNoiseEdit     matlab.ui.control.EditField
        text3                 matlab.ui.control.Label
        ChargeFilterEdit      matlab.ui.control.EditField
        text6                 matlab.ui.control.Label
        NoiseThreholdIntEdit  matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        MassErrorPpmEdit      matlab.ui.control.EditField
        Extract               matlab.ui.control.Button
        Reset                 matlab.ui.control.Button
        Lamp                  matlab.ui.control.Lamp
        ViewlogButton         matlab.ui.control.Button
        uipanel3              matlab.ui.container.Panel
        text8                 matlab.ui.control.Label
        uipanel5              matlab.ui.container.Panel
        BrowseInput           matlab.ui.control.Button
        BrowseOutput          matlab.ui.control.Button
        InputPath             matlab.ui.control.EditField
        OutputPath            matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function edit3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FeatureExtraction_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to FeatureExtraction (see VARARGIN)
            %
            %             % Choose default command line output for FeatureExtraction
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
            
        end

        % Callback function
        function BrowseInputFile_Callback(app, event)
            % hObject    handle to BrowseInputFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % inFolder = uigetdir();
           
        end

        % Callback function
        function BrowseOutputPath_Callback(app, event)
            % hObject    handle to BrowseOutputPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
          
        end

        % Button pushed function: Extract
        function Extract_Callback(app, event)
            % hObject    handle to Extract (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.Lamp.Color= 'red';
            
            diary(fullfile(getappdata(0, 'outputdatapath'), '/log.txt'));
           
            extractWorkflow = biotracs.openms.model.MetaboExtractionWorkflow();
            extractWorkflow.getConfig()...
                .updateParamValue('WorkingDirectory',  getappdata(0, 'outputdatapath') );
            
            inputAdpter = extractWorkflow.getNode('MzFileImporter');
            
            files = getappdata(0, 'inputdatapath');
            for i=1:length(files)
                inputAdpter.addInputFilePath(  files{i} );
            end
            inputAdpter.getConfig()...
                .updateParamValue( 'FileExtensionFilter', 'mzXML');
            
            massErrorPpmEdit = app.MassErrorPpmEdit.Value;
            massErrorPpmEdit= str2double(massErrorPpmEdit);
            
            noiseThreholdIntEdit= app.NoiseThreholdIntEdit.Value;
            noiseThreholdIntEdit= str2double(noiseThreholdIntEdit);
            
            signalToNoiseEdit= app.SignalToNoiseEdit.Value;
            signalToNoiseEdit= str2double(signalToNoiseEdit);
            
            
            charge= app.ChargeFilterEdit.Value;
            charge = str2double(regexp(charge,'\d*','match')');
%             charge= str2double(charge);
            
            featureFinder = extractWorkflow.getNode('FeatureFinderMetabo');
            featureFinder.getConfig()...
                .updateParamValue('NoiseThresholdInt', noiseThreholdIntEdit)...
                .updateParamValue('MassErrorPpm',massErrorPpmEdit)...
                .updateParamValue('SignalToNoise',signalToNoiseEdit);
            
            fileFilter = extractWorkflow.getNode('FileFilter');
            fileFilter.getConfig()...
                .updateParamValue('Charge', charge);
            
            
            extractWorkflow.run();
            
            if extractWorkflow.isEnded
                app.Lamp.Color= 'green';
            end
            diary off;
        end

        % Button pushed function: ViewlogButton
        function ViewlogButtonPushed(app, event)
            outdir = getappdata(0, 'outputdatapath');
            system(['notepad ' fullfile(outdir,'/log.txt')])
        end

        % Button pushed function: BrowseInput
        function BrowseInputButtonPushed(app, event)
            inFolder = uipickfiles() ;
            if ~isempty(inFolder) && ~isnumeric(inFolder)
                app.InputPath.Value= char(join(inFolder, ','));
                setappdata(0,'inputdatapath', inFolder);
            end
        end

        % Button pushed function: BrowseOutput
        function BrowseOutputButtonPushed(app, event)
            outFolder = uigetdir();
            if ~isempty(outFolder) && ~isnumeric(outFolder)
                app.OutputPath.Value= outFolder;
                setappdata(0,'outputdatapath', outFolder);
            end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            close(gcbf)
            FeatureExtraction_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FeatureExtraction';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Parameter';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Text = 'SignalToNoise';
            app.SignalToNoiseEdit = uieditfield(app.uipanel2, 'text');
            app.SignalToNoiseEdit.Tooltip = {'Minimum signal-to-noise a mass trace should have. (default: 3)'};
            app.SignalToNoiseEdit.Value = '3';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Text = 'ChargeFilter';
            app.ChargeFilterEdit = uieditfield(app.uipanel2, 'text');
            app.ChargeFilterEdit.Tooltip = {'Charge range to extract. '; '(default: 1-1)'; ''};
            app.ChargeFilterEdit.Value = '1-1';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'NoiseThresholdInt';
            app.NoiseThreholdIntEdit = uieditfield(app.uipanel2, 'text');
            app.NoiseThreholdIntEdit.Tooltip = {'Intensity threshold below which peaks are regarded as noise. (default: 1e4)'};
            app.NoiseThreholdIntEdit.Value = '1e4';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = 'MassErrorPpm';
            app.MassErrorPpmEdit = uieditfield(app.uipanel2, 'text');
            app.MassErrorPpmEdit.Tooltip = {'Allowed mass deviation in ppm. (default: 5)'};
            app.MassErrorPpmEdit.Value = '5';
            app.Extract = uibutton(app.uipanel2, 'push');
            app.Extract.ButtonPushedFcn = createCallbackFcn(app, @Extract_Callback, true);
            app.Extract.Tooltip = {'Extraction of Features'};
            app.Extract.Text = 'Extract';
            app.Reset = uibutton(app.uipanel2, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.Lamp = uilamp(app.uipanel2);
            app.ViewlogButton = uibutton(app.uipanel2, 'push');
            app.ViewlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlogButtonPushed, true);
            app.ViewlogButton.Text = 'View log';
            app.uipanel3 = uipanel(app.figure1);
            app.text8 = uilabel(app.uipanel3);
            app.text8.Text = 'Feature Extraction using OpenMS';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Input/output files';
            app.BrowseInput = uibutton(app.uipanel5, 'push');
            app.BrowseInput.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInput.Text = 'Browse for input File Path';
            app.BrowseOutput = uibutton(app.uipanel5, 'push');
            app.BrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutput.Text = 'Browse for output File Path';
            app.InputPath = uieditfield(app.uipanel5, 'text');
            app.InputPath.Editable = 'off';
            app.OutputPath = uieditfield(app.uipanel5, 'text');
            app.OutputPath.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureExtraction_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @FeatureExtraction_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end