classdef FeatureLinking_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uipanel5                       matlab.ui.container.Panel
        reset                          matlab.ui.control.Button
        Link                           matlab.ui.control.Button
        uipanel6                       matlab.ui.container.Panel
        text6                          matlab.ui.control.Label
        MaxDifferenceRtDistanceMAPCEdit  matlab.ui.control.EditField
        text5                          matlab.ui.control.Label
        MaxDifferenceMzDistanceMAPCEdit  matlab.ui.control.EditField
        text4                          matlab.ui.control.Label
        ReferenceEdit                  matlab.ui.control.EditField
        uipanel7                       matlab.ui.container.Panel
        text3                          matlab.ui.control.Label
        MaxDifferenceRtDistanceFLEdit  matlab.ui.control.EditField
        text2                          matlab.ui.control.Label
        MaxDifferenceMzDistanceFLEdit  matlab.ui.control.EditField
        uipanel8                       matlab.ui.container.Panel
        MinConsensusSizeEdit           matlab.ui.control.EditField
        text1                          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        ViewlogButton                  matlab.ui.control.Button
        uipanel9                       matlab.ui.container.Panel
        text14                         matlab.ui.control.Label
        uipanel5_2                     matlab.ui.container.Panel
        BrowseInput                    matlab.ui.control.Button
        BrowseOutput                   matlab.ui.control.Button
        InputPath                      matlab.ui.control.EditField
        OutputPath                     matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FeatureLinking_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to FeatureLinking (see VARARGIN)
            %
            %             % Choose default command line output for FeatureLinking
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);

        end

        % Callback function
        function BrowseInputPath_Callback(app, event)
            % hObject    handle to BrowseInputPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            inFolder = uipickfiles ;
            app.InputPath.Value= char(join(inFolder, ','));

            % inFolder = uigetdir();
            setappdata(0,'inputdatapath', inFolder);
        end

        % Callback function
        function BrowseOutputPath_Callback(app, event)
            % hObject    handle to BrowseOutputPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outFolder = uigetdir();
            app.OutputPath.Value= inFolder;
            setappdata(0,'outputdatapath', outFolder);
        end

        % Button pushed function: Link
        function Link_Callback(app, event)
            % hObject    handle to Link (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.Lamp.Color= 'red';
            diary(fullfile(getappdata(0, 'outputdatapath'), '/log.txt'));
            linkWorkflow = biotracs.openms.model.MetaboLinkingWorkflow();
            linkWorkflow.getConfig()...
                .updateParamValue('WorkingDirectory',  getappdata(0, 'outputdatapath') );
            
            inputAdpter = linkWorkflow.getNode('MzFileImporter');
            files = getappdata(0, 'inputdatapath');
            for i=1:length(files)
                inputAdpter.addInputFilePath(  files{i} );
            end
            inputAdpter.getConfig()...
                .updateParamValue( 'FileExtensionFilter', 'featureXML');
            
            maxDifferenceRtDistanceMAPCEdit = app.MaxDifferenceRtDistanceMAPCEdit.Value;
            maxDifferenceRtDistanceMAPCEdit= str2double(maxDifferenceRtDistanceMAPCEdit);
            
            maxDifferenceMzDistanceMAPCEdit = app.MaxDifferenceMzDistanceMAPCEdit.Value;
            maxDifferenceMzDistanceMAPCEdit= str2double(maxDifferenceMzDistanceMAPCEdit);
            
            referenceEdit = app.ReferenceEdit.Value;
            
            maxDifferenceRtDistanceFLEdit = app.MaxDifferenceRtDistanceFLEdit.Value;
            maxDifferenceRtDistanceFLEdit= str2double(maxDifferenceRtDistanceFLEdit);
            
            maxDifferenceMzDistanceFLEdit = app.MaxDifferenceMzDistanceFLEdit.Value;
            maxDifferenceMzDistanceFLEdit= str2double(maxDifferenceMzDistanceFLEdit);
            
            minConsensusSizeEdit = app.MinConsensusSizeEdit.Value;
            minConsensusSizeEdit= str2double(minConsensusSizeEdit);
            
            
            mapAlignerPoseClustering = linkWorkflow.getNode('MapAlignerPoseClustering');
            mapAlignerPoseClustering.getConfig()...
                .updateParamValue('MaxDifferenceRtDistance', maxDifferenceRtDistanceMAPCEdit)...
                .updateParamValue('MaxDifferenceMzDistance',maxDifferenceMzDistanceMAPCEdit)...
                .updateParamValue('Reference',referenceEdit);
            
            featureLinkerUnlabeledQT = linkWorkflow.getNode('FeatureLinkerUnlabeledQT');
            featureLinkerUnlabeledQT.getConfig()...
                .updateParamValue('RtMaxDifference', maxDifferenceRtDistanceFLEdit) ...
                .updateParamValue('MzMaxDifference', maxDifferenceMzDistanceFLEdit) ;
            
            qualityFileFilter = linkWorkflow.getNode('QualityFileFilter');
            qualityFileFilter.getConfig()...
                .updateParamValue('MinConsensusSize', [minConsensusSizeEdit,Inf]);
            
            linkWorkflow.run();
            
            if linkWorkflow.isEnded
                app.Lamp.Color= 'green';
            end
            diary off;
        end

        % Button pushed function: ViewlogButton
        function ViewlogButtonPushed(app, event)
            outdir = getappdata(0, 'outputdatapath');
            system(['notepad ' fullfile(outdir,'/log.txt')]);
        end

        % Button pushed function: BrowseInput
        function BrowseInputButtonPushed(app, event)
            inFolder = uipickfiles ;
            if ~isempty(inFolder) && ~isnumeric(inFolder)
                app.InputPath.Value= char(join(inFolder, ','));
                setappdata(0,'inputdatapath', inFolder);
            end
        end

        % Button pushed function: BrowseOutput
        function BrowseOutputButtonPushed(app, event)
            outFolder = uigetdir();
            if ~isempty(outFolder) && ~isnumeric(outFolder)
                app.OutputPath.Value= outFolder;
                setappdata(0,'outputdatapath', outFolder);
            end
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            close(gcbf)
            FeatureLinking_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FeatureLinking';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Parameter';
            app.reset = uibutton(app.uipanel5, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'Reset';
            app.Link = uibutton(app.uipanel5, 'push');
            app.Link.ButtonPushedFcn = createCallbackFcn(app, @Link_Callback, true);
            app.Link.Tooltip = {'Linking of features from different samples'};
            app.Link.Text = 'Link';
            app.uipanel6 = uipanel(app.uipanel5);
            app.uipanel6.Title = 'MapAlignerPoseClustering';
            app.text6 = uilabel(app.uipanel6);
            app.text6.Text = 'MaxDifferenceRtDistance (s)';
            app.MaxDifferenceRtDistanceMAPCEdit = uieditfield(app.uipanel6, 'text');
            app.MaxDifferenceRtDistanceMAPCEdit.Tooltip = {'Maximum allowed difference in RT in seconds. (default: 60)'};
            app.MaxDifferenceRtDistanceMAPCEdit.Value = '100';
            app.text5 = uilabel(app.uipanel6);
            app.text5.Text = 'MaxDifferenceMzDistance (ppm)';
            app.MaxDifferenceMzDistanceMAPCEdit = uieditfield(app.uipanel6, 'text');
            app.MaxDifferenceMzDistanceMAPCEdit.Tooltip = {'Maximum allowed difference in m/z'; ' (unit defined by unit parameter). (default: 10)'};
            app.MaxDifferenceMzDistanceMAPCEdit.Value = '5';
            app.text4 = uilabel(app.uipanel6);
            app.text4.Text = 'Reference';
            app.ReferenceEdit = uieditfield(app.uipanel6, 'text');
            app.ReferenceEdit.Tooltip = {'Name of the sample or Qc used for aligning on all the samples'};
            app.uipanel7 = uipanel(app.uipanel5);
            app.uipanel7.Title = 'FeatureLinkerUnlabeledQT';
            app.text3 = uilabel(app.uipanel7);
            app.text3.Text = 'MaxDifferenceRtDistance (s)';
            app.MaxDifferenceRtDistanceFLEdit = uieditfield(app.uipanel7, 'text');
            app.MaxDifferenceRtDistanceFLEdit.Tooltip = {'Maximum allowed difference in RT in seconds. (default: 100)'};
            app.MaxDifferenceRtDistanceFLEdit.Value = '100';
            app.text2 = uilabel(app.uipanel7);
            app.text2.Text = 'MaxDifferenceMzDistance (ppm)';
            app.MaxDifferenceMzDistanceFLEdit = uieditfield(app.uipanel7, 'text');
            app.MaxDifferenceMzDistanceFLEdit.Tooltip = {'Maximum allowed difference in m/z (unit defined by MzUnit). (default: 5)'};
            app.MaxDifferenceMzDistanceFLEdit.Value = '5';
            app.uipanel8 = uipanel(app.uipanel5);
            app.uipanel8.Title = 'ConsensusFileFilter';
            app.MinConsensusSizeEdit = uieditfield(app.uipanel8, 'text');
            app.MinConsensusSizeEdit.Tooltip = {'Minimum number of hit of each feature detected across maps  in a consensus. (default: 2)'};
            app.MinConsensusSizeEdit.Value = '2';
            app.text1 = uilabel(app.uipanel8);
            app.text1.Text = 'MinConsensusSize';
            app.Lamp = uilamp(app.uipanel5);
            app.ViewlogButton = uibutton(app.uipanel5, 'push');
            app.ViewlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlogButtonPushed, true);
            app.ViewlogButton.Text = 'View log';
            app.uipanel9 = uipanel(app.figure1);
            app.text14 = uilabel(app.uipanel9);
            app.text14.Text = 'Feature Linking using OpenMS';
            app.uipanel5_2 = uipanel(app.figure1);
            app.uipanel5_2.Title = 'Input/output files';
            app.BrowseInput = uibutton(app.uipanel5_2, 'push');
            app.BrowseInput.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInput.Text = 'Browse for input File Path';
            app.BrowseOutput = uibutton(app.uipanel5_2, 'push');
            app.BrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutput.Text = 'Browse for output File Path';
            app.InputPath = uieditfield(app.uipanel5_2, 'text');
            app.InputPath.Editable = 'off';
            app.OutputPath = uieditfield(app.uipanel5_2, 'text');
            app.OutputPath.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureLinking_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @FeatureLinking_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end