classdef PreProcessing_App_old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleOptimizedTab            matlab.ui.Figure
        tab1Panel                     matlab.ui.container.Panel
        uipanel7                      matlab.ui.container.Panel
        text14                        matlab.ui.control.Label
        uipanel8                      matlab.ui.container.Panel
        MetadataFilePath              matlab.ui.control.Button
        InputPositiveFilePath         matlab.ui.control.Button
        checkbox1                     matlab.ui.control.CheckBox
        checkbox2                     matlab.ui.control.CheckBox
        InputNegativeFilePath         matlab.ui.control.Button
        OutputFilePath                matlab.ui.control.Button
        Reset                         matlab.ui.control.Button
        uipanel28                     matlab.ui.container.Panel
        text67                        matlab.ui.control.Label
        AnnotationNamesEdit           matlab.ui.control.EditField
        text68                        matlab.ui.control.Label
        PCANPMLabelViewEdit           matlab.ui.control.EditField
        Process                       matlab.ui.control.Button
        tab1text                      matlab.ui.control.Label
        tab2text                      matlab.ui.control.Label
        tab2Panel                     matlab.ui.container.Panel
        uipanel19                     matlab.ui.container.Panel
        nListOfNames                  matlab.ui.control.EditField
        text41                        matlab.ui.control.Label
        uipanel21                     matlab.ui.container.Panel
        text46                        matlab.ui.control.Label
        nSheetEdit                    matlab.ui.control.EditField
        uipanel22                     matlab.ui.container.Panel
        nBFLablesViewPCAEdit          matlab.ui.control.EditField
        text49                        matlab.ui.control.Label
        nBFLabelViewEdit              matlab.ui.control.EditField
        text50                        matlab.ui.control.Label
        nThresholdBlankEdit           matlab.ui.control.EditField
        text51                        matlab.ui.control.Label
        uipanel23                     matlab.ui.container.Panel
        nPRFLAbelsViewPCAEdit         matlab.ui.control.EditField
        text52                        matlab.ui.control.Label
        nPRFLAbelsViewPlotsEdit       matlab.ui.control.EditField
        text53                        matlab.ui.control.Label
        nGroupListEdit                matlab.ui.control.EditField
        text54                        matlab.ui.control.Label
        nThresholdPercRuleFilterEdit  matlab.ui.control.EditField
        text55                        matlab.ui.control.Label
        text56                        matlab.ui.control.Label
        nMethodOfFilteringEdit        matlab.ui.control.EditField
        uipanel24                     matlab.ui.container.Panel
        nQcCVLabelsViewPCAEdit        matlab.ui.control.EditField
        text57                        matlab.ui.control.Label
        nQcCVLabelsViewPlotsEdit      matlab.ui.control.EditField
        text58                        matlab.ui.control.Label
        nQcCVLoqEdit                  matlab.ui.control.EditField
        text59                        matlab.ui.control.Label
        nQcCVThresholdEdit            matlab.ui.control.EditField
        text60                        matlab.ui.control.Label
        uipanel25                     matlab.ui.container.Panel
        text63                        matlab.ui.control.Label
        nIDFLAbelsViewPCAEdit         matlab.ui.control.EditField
        uipanel26                     matlab.ui.container.Panel
        nIaDFLAbelsViewPCAEdit        matlab.ui.control.EditField
        text64                        matlab.ui.control.Label
        uipanel20                     matlab.ui.container.Panel
        text42                        matlab.ui.control.Label
        nHeaderToUseEdit              matlab.ui.control.EditField
        text43                        matlab.ui.control.Label
        nUserIDHeaderEdit             matlab.ui.control.EditField
        text44                        matlab.ui.control.Label
        nCDALabelViewEdit             matlab.ui.control.EditField
        text45                        matlab.ui.control.Label
        nCDALabelsViewPCAEdit         matlab.ui.control.EditField
        tab3text                      matlab.ui.control.Label
        tab3Panel                     matlab.ui.container.Panel
        uipanel29                     matlab.ui.container.Panel
        pListOfNames                  matlab.ui.control.EditField
        text69                        matlab.ui.control.Label
        uipanel30                     matlab.ui.container.Panel
        text70                        matlab.ui.control.Label
        pHeaderToUseEdit              matlab.ui.control.EditField
        text71                        matlab.ui.control.Label
        pUserIDHeaderEdit             matlab.ui.control.EditField
        text72                        matlab.ui.control.Label
        pCDALabelViewEdit             matlab.ui.control.EditField
        text73                        matlab.ui.control.Label
        pCDALabelsViewPCAEdit         matlab.ui.control.EditField
        uipanel31                     matlab.ui.container.Panel
        text74                        matlab.ui.control.Label
        pSheetEdit                    matlab.ui.control.EditField
        uipanel32                     matlab.ui.container.Panel
        pBFLablesViewPCAEdit          matlab.ui.control.EditField
        text75                        matlab.ui.control.Label
        pBFLabelViewEdit              matlab.ui.control.EditField
        text76                        matlab.ui.control.Label
        pThresholdBlankEdit           matlab.ui.control.EditField
        text77                        matlab.ui.control.Label
        uipanel33                     matlab.ui.container.Panel
        pPRFLAbelsViewPCAEdit         matlab.ui.control.EditField
        text78                        matlab.ui.control.Label
        pPRFLAbelsViewPlotsEdit       matlab.ui.control.EditField
        text79                        matlab.ui.control.Label
        pGroupListEdit                matlab.ui.control.EditField
        text80                        matlab.ui.control.Label
        pThresholdPercRuleFilterEdit  matlab.ui.control.EditField
        text81                        matlab.ui.control.Label
        text82                        matlab.ui.control.Label
        pMethodOfFilteringEdit        matlab.ui.control.EditField
        uipanel34                     matlab.ui.container.Panel
        pQcCVLabelsViewPCAEdit        matlab.ui.control.EditField
        text83                        matlab.ui.control.Label
        pQcCVLabelsViewPlotsEdit      matlab.ui.control.EditField
        text84                        matlab.ui.control.Label
        pQcCVLoqEdit                  matlab.ui.control.EditField
        text85                        matlab.ui.control.Label
        pQcCVThresholdEdit            matlab.ui.control.EditField
        text86                        matlab.ui.control.Label
        uipanel35                     matlab.ui.container.Panel
        text87                        matlab.ui.control.Label
        pIDFLAbelsViewPCAEdit         matlab.ui.control.EditField
        uipanel36                     matlab.ui.container.Panel
        pIaDFLAbelsViewPCAEdit        matlab.ui.control.EditField
        text88                        matlab.ui.control.Label
    end

    
    methods (Access = private)
        function ClickOnTab(app, hObject, ~, handles)
            m = str2double(get(hObject,'Tag'));
            
            for i = 1:app.TabsNumber
                n = num2str(i);
                if i == m
                    set(app.(['a',n]),'Color',app.selectedTabColor)
                    set(app.(['t',n]),'BackgroundColor',app.selectedTabColor)
                    set(app.(['tab',n,'Panel']),'Visible','on')
                else
                     TabColor =  [0.89 0.68 0.67];
                    set(app.(['a',n]),'Color',TabColor)
                    set(app.(['t',n]),'BackgroundColor',TabColor)
                    set(app.(['tab',n,'Panel']),'Visible','off')
            %         set(app.(['a',n]),'Color',app.unselectedTabColor)
            %         set(app.(['t',n]),'BackgroundColor',app.unselectedTabColor)
            %         set(app.(['tab',n,'Panel']),'Visible','off')
                end
            end
        end
        
        function handles = TabsFun(app, handles, TabFontSize, TabNames)
            
            % Set the colors indicating a selected/unselected tab
            app.selectedTabColor=get(app.tab1Panel,'BackgroundColor');
            app.unselectedTabColor=app.selectedTabColor-0.1;
            
            % Create Tabs
            TabsNumber = length(TabNames);
            app.TabsNumber = TabsNumber;
            % TabColor = app.selectedTabColor;
            TabColor = [0.89 0.68 0.67];
            
            for i = 1:TabsNumber
                n = num2str(i);
            
                % Get text objects position
                set(app.(['tab',n,'text']),'Units','normalized')
                pos=get(app.(['tab',n,'text']),'Position');
            
                % Create axes with callback function
                app.(['a',n]) = axes('Units','normalized',...
                                'Box','on',...
                                'XTick',[],...
                                'YTick',[],...
                                'Color',TabColor,...
                                'Position',[pos(1) pos(2) pos(3) pos(4)+0.01],...
                                'Tag',n,...
                                'ButtonDownFcn',[mfilename,'(''ClickOnTab'',gcbo,[],guidata(gcbo))']);
            
                % Create text with callback function
                app.(['t',n]) = text('String',TabNames{i},...
                                'Units','normalized',...
                                'Position',[pos(3),pos(2)/2+pos(4)],...
                                'HorizontalAlignment','left',...
                                'VerticalAlignment','middle',...
                                'Margin',0.001,...
                                'FontSize',TabFontSize,...
                                'Backgroundcolor',TabColor,...
                                'Tag',n,...
                                'ButtonDownFcn',[mfilename,'(''ClickOnTab'',gcbo,[],guidata(gcbo))']);
            
            %     TabColor = app.unselectedTabColor;
                TabColor =  [0.89 0.68 0.67];
            end
            
            % Manage panels (place them in the correct position and manage visibilities)
            set(app.tab1Panel,'Units','normalized')
            pan1pos=get(app.tab1Panel,'Position');
            set(app.tab1text,'Visible','off')
            for i = 2:TabsNumber
                n = num2str(i);
                set(app.(['tab',n,'Panel']),'Units','normalized')
                set(app.(['tab',n,'Panel']),'Position',pan1pos)
                set(app.(['tab',n,'Panel']),'Visible','off')
                set(app.(['tab',n,'text']),'Visible','off')
            end
        end
        
        function edit103_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pQcCVLoqEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PreProcessing_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to SimpleOptimizedTabs2 (see VARARGIN)
%             
%             % Choose default command line output for SimpleOptimizedTabs2
%             app.output = hObject;
%             
%             %% Tabs Code
%             % Settings
%             TabFontSize = 10;
%             TabNames = {'Preprocessing','Negative mode', 'Positive mode'};
%             FigWidth = 0.54;
%             
%             % Figure resize
%             set(app.SimpleOptimizedTab,'Units','normalized')
%             pos = get(app. SimpleOptimizedTab, 'Position');
%             set(app. SimpleOptimizedTab, 'Position', [pos(1) pos(2) FigWidth pos(4)])
%             
%             % Tabs Execution
%             handles = TabsFun(handles,TabFontSize,TabNames);
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: InputNegativeFilePath
        function InputNegativeFilePath_Callback(app, event)
            % hObject    handle to InputNegativeFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            inNegFolder = uigetdir();
            setappdata(0,'inputNegDatapath', inNegFolder);
        end

        % Button pushed function: InputPositiveFilePath
        function InputPositiveFilePath_Callback(app, event)
            % hObject    handle to InputPositiveFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            inPosFolder = uigetdir();
            setappdata(0,'inputPosDatapath', inPosFolder);
        end

        % Button pushed function: MetadataFilePath
        function MetadataFilePath_Callback(app, event)
            % hObject    handle to MetadataFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [MetaData, path] = uigetfile('*.xlsx');
            MetadataPath = [path MetaData];
            app.MetaDataPath=MetadataPath;
            setappdata(0,'metaDatapath', MetadataPath);
        end

        % Button pushed function: OutputFilePath
        function OutputFilePath_Callback(app, event)
            % hObject    handle to OutputFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outFolder = uigetdir();
            setappdata(0,'outputdatapath', outFolder);
        end

        % Button pushed function: Process
        function Process_Callback(app, event)
            % hObject    handle to Process (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            workflow = bioapps.mimosa.model.PreprocessingWorkflow();
            workflow.getConfig()...
                .updateParamValue( 'WorkingDirectory',  getappdata(0, 'outputdatapath') );
            
            %% Import SampleMetaData
            inputAdpter = workflow.getNode('SampleMetaDataFileImporter');
            inputAdpter.addInputFilePath(  getappdata(0, 'metaDatapath'));
            
            %% Import FeatureSet
            pinputAdpter = workflow.getNode('PosConsensusFileImporter');
            if ~isempty(getappdata(0, 'inputPosDatapath'))
                pinputAdpter.addInputFilePath(  getappdata(0, 'inputPosDatapath'));
                pinputAdpter.getConfig()...
                    .updateParamValue( 'FileExtensionFilter', 'csv');
            end
            % ConsensusDataParser
            w1 = workflow.getNode('PosModePreprocessingWorkflow');
            
            psheetEdit = get(app.pSheetEdit,'String');
            
            pmetaTableParser = w1.getNode('MetaTableParser');
            pmetaTableParser.getConfig()...
                .updateParamValue('Sheet', psheetEdit);
            
            pconsensusDataExtractor = w1.getNode('ConsensusDataExtractor');
            pconsensusDataExtractor.getConfig()...
                .updateParamValue('Polarity', 'Pos');
            
            pHeaderToUseEdit = get(app.pHeaderToUseEdit,'String');
            pHeaderToUseEdit= regexprep(pHeaderToUseEdit, '\s+', '');
            
            pUserIDHeaderEdit = get(app.pUserIDHeaderEdit,'String');
            
            % Annotate
            pconsensusDataAnnotator = w1.getNode('ConsensusDataAnnotator');
            pconsensusDataAnnotator.getConfig()...
                .updateParamValue('HeadersToUse', strsplit(pHeaderToUseEdit, ','))...
                .updateParamValue('UserIdHeader', pUserIDHeaderEdit);


            pparamValue = get(app.pCDALabelViewEdit,'String');
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewConsensusDataAnnotator = w1.getNode('ViewConsensusDataAnnotator');
            pviewConsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = get(app.pCDALabelsViewPCAEdit,'String');
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCAconsensusDataAnnotator = w1.getNode('ViewPcaConsensusDataAnnotator');
            pPCAconsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % DataRemove
            pListOfNames = get(app.pListOfNames,'String');
            pListOfNames = regexprep(pListOfNames, '\s+', '');
            pdataSelector = w1.getNode('DataSelector');
            pdataSelector.getConfig().updateParamValue(...
                'ListOfNames', strsplit(pListOfNames, ','));
            
            % Blank Filter
            pThresholdBlankEdit = get(app.pThresholdBlankEdit,'String');
            pThresholdBlankEdit = str2num(pThresholdBlankEdit);
            
            pblankFilter = w1.getNode('BlankFilter');
            pblankFilter.getConfig()...
                .updateParamValue('BlankIntensityRatio', pThresholdBlankEdit);
            
            pparamValue = get(app.pBFLabelViewEdit,'String');
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewBlankFilter = w1.getNode('ViewBlankFilter');
            pviewBlankFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = get(app.pBFLablesViewPCAEdit,'String');
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCABlankFilter = w1.getNode('ViewPcaBlankFilter');
            pPCABlankFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % Filter by 80 %
            pThresholdPercRuleFilterEdit = get(app.pThresholdPercRuleFilterEdit,'String');
            pThresholdPercRuleFilterEdit = str2num(pThresholdPercRuleFilterEdit);
            pGroupListEdit = get(app.pGroupListEdit,'String');
            pGroupListEdit =regexprep(pGroupListEdit, '\s+', '');
            pMethodOfFilteringEdit = get(app.pMethodOfFilteringEdit,'String');
            
            ppercentageRuleFilter = w1.getNode('PercentageRuleFilter');
            ppercentageRuleFilter.getConfig()...
                .updateParamValue('PercentageRuleThreshold', pThresholdPercRuleFilterEdit)...
                .updateParamValue('GroupList',strsplit(pGroupListEdit, ',') ) ...
                .updateParamValue('MethodOfFiltering', pMethodOfFilteringEdit);
            
            
            pparamValue = get(app.pPRFLAbelsViewPlotsEdit,'String');
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewThresholdPercRuleFilter = w1.getNode('ViewPercentageRuleFilter');
            pviewThresholdPercRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = get(app.pPRFLAbelsViewPCAEdit,'String');
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCAThresholdPercRuleFilter = w1.getNode('ViewPcaPercentageRuleFilter');
            pPCAThresholdPercRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % FilterCv 20%
            pQcCVThresholdEdit = get(app.pQcCVThresholdEdit,'String');
            pQcCVThresholdEdit = str2num(pQcCVThresholdEdit);
            pQcCVLoqEdit = get(app.pQcCVLoqEdit,'String');
            pQcCVLoqEdit = str2num(pQcCVLoqEdit);
            
            pqcCvFilter = w1.getNode('QcCvFilter');
            pqcCvFilter.getConfig()...
                .updateParamValue('QcCvThreshold', pQcCVThresholdEdit)...
                .updateParamValue('PercentageRuleThreshold', 0)...
                .updateParamValue('LoQ', pQcCVLoqEdit);
            
            
            pparamValue = get(app.pQcCVLabelsViewPlotsEdit,'String');
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewQcCV = w1.getNode('ViewQcCvFilter');
            pviewQcCV.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = get(app.pQcCVLabelsViewPCAEdit,'String');
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCAQcCV = w1.getNode('ViewPcaQcCvFilter');
            pPCAQcCV.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            pparamValue = get(app.pIaDFLAbelsViewPCAEdit,'String');
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPcaIntraBatchDriftCorrector = w1.getNode('ViewPcaIntraBatchDriftCorrector');
            pPcaIntraBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            pparamValue = get(app.pIDFLAbelsViewPCAEdit,'String');
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPcaInterBatchDriftCorrector = w1.getNode('ViewPcaInterBatchDriftCorrector');
            pPcaInterBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            %% Negative Workflow
            
            % Import FeatureSet
            ninputAdpter = workflow.getNode('NegConsensusFileImporter');
            if ~isempty(getappdata(0, 'inputNegDatapath'))
            
                ninputAdpter.addInputFilePath( getappdata(0, 'inputNegDatapath'));
                ninputAdpter.getConfig()...
                    .updateParamValue( 'FileExtensionFilter', 'csv');
            end
            % ConsensusDataParser
            w2 = workflow.getNode('NegModePreprocessingWorkflow');
            
            nsheetEdit = get(app.nSheetEdit,'String');
            
            nmetaTableParser = w2.getNode('MetaTableParser');
            nmetaTableParser.getConfig()...
                .updateParamValue('Sheet', nsheetEdit);
            
            nconsensusDataExtractor = w2.getNode('ConsensusDataExtractor');
            nconsensusDataExtractor.getConfig()...
                .updateParamValue('Polarity', 'Neg');
            
            nHeaderToUseEdit = get(app.nHeaderToUseEdit,'String');
            nHeaderToUseEdit= regexprep(nHeaderToUseEdit, '\s+', '');
            nUserIDHeaderEdit = get(app.nUserIDHeaderEdit,'String');
            
            
            % Annotate
            nconsensusDataAnnotator = w2.getNode('ConsensusDataAnnotator');
            nconsensusDataAnnotator.getConfig()...
                .updateParamValue('HeadersToUse', strsplit(nHeaderToUseEdit, ','))...
                .updateParamValue('UserIdHeader',nUserIDHeaderEdit);
            
            
            nparamValue = get(app.nCDALabelViewEdit,'String');
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewConsensusDataAnnotator = w2.getNode('ViewConsensusDataAnnotator');
            nviewConsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = get(app.nCDALabelsViewPCAEdit,'String');
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPCAconsensusDataAnnotator = w2.getNode('ViewPcaConsensusDataAnnotator');
            nPCAconsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % DataRemove
            nListOfNames = get(app.nListOfNames,'String');
            nListOfNames = regexprep(nListOfNames, '\s+', '');
            ndataSelector = w2.getNode('DataSelector');
            ndataSelector.getConfig().updateParamValue(...
                'ListOfNames', strsplit(nListOfNames, ','));
            
            % Blank Filter
            nThresholdBlankEdit = get(app.nThresholdBlankEdit,'String');
            nThresholdBlankEdit = str2num(nThresholdBlankEdit);
            
            nblankFilter = w2.getNode('BlankFilter');
            nblankFilter.getConfig()...
                .updateParamValue('BlankIntensityRatio', nThresholdBlankEdit);
            
            nparamValue = get(app.nBFLabelViewEdit,'String');
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewBlankFilter = w2.getNode('ViewBlankFilter');
            nviewBlankFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = get(app.nBFLablesViewPCAEdit,'String');
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaBlankFilter = w2.getNode('ViewPcaBlankFilter');
            nPcaBlankFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % Filter by 80 %
            nThresholdPercRuleFilterEdit = get(app.nThresholdPercRuleFilterEdit,'String');
            nThresholdPercRuleFilterEdit = str2num(nThresholdPercRuleFilterEdit);
            nGroupListEdit = get(app.nGroupListEdit,'String');
            nGroupListEdit = regexprep(nGroupListEdit, '\s+', '');
            nMethodOfFilteringEdit = get(app.nMethodOfFilteringEdit,'String');
            
            npercentageRuleFilter = w2.getNode('PercentageRuleFilter');
            npercentageRuleFilter.getConfig()...
                .updateParamValue('PercentageRuleThreshold', nThresholdPercRuleFilterEdit)...
                .updateParamValue('GroupList', strsplit(nGroupListEdit, ',')) ...
                .updateParamValue('MethodOfFiltering', nMethodOfFilteringEdit);
            
            
            nparamValue = get(app.nPRFLAbelsViewPlotsEdit,'String');
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewPercentageRuleFilter = w2.getNode('ViewPercentageRuleFilter');
            nviewPercentageRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = get(app.nPRFLAbelsViewPCAEdit,'String');
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaPercentageRuleFilter = w2.getNode('ViewPcaPercentageRuleFilter');
            nPcaPercentageRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % FilterCv 20%
            nQcCVThresholdEdit = get(app.nQcCVThresholdEdit,'String');
            nQcCVThresholdEdit = str2num(nQcCVThresholdEdit);
            nQcCVLoqEdit = get(app.nQcCVLoqEdit,'String');
            nQcCVLoqEdit = str2num(nQcCVLoqEdit);
            
            nqcCvFilter = w2.getNode('QcCvFilter');
            nqcCvFilter.getConfig()...
                .updateParamValue('QcCvThreshold', nQcCVThresholdEdit)...
                .updateParamValue('PercentageRuleThreshold', 0)...
                .updateParamValue('LoQ', nQcCVLoqEdit);
            
            
            nparamValue = get(app.nQcCVLabelsViewPlotsEdit,'String');
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewQcCvFilter = w2.getNode('ViewQcCvFilter');
            nviewQcCvFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = get(app.nQcCVLabelsViewPCAEdit,'String');
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaQcCvFilter = w2.getNode('ViewPcaQcCvFilter');
            nPcaQcCvFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            nparamValue = get(app.nIaDFLAbelsViewPCAEdit,'String');
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaIntraBatchDriftCorrector = w2.getNode('ViewPcaIntraBatchDriftCorrector');
            nPcaIntraBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            
            nparamValue = get(app.nIDFLAbelsViewPCAEdit,'String');
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaInterBatchDriftCorrector = w2.getNode('ViewPcaInterBatchDriftCorrector');
            nPcaInterBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            annotationNamesEdit = get(app.AnnotationNamesEdit,'String');
            annotationNamesEdit=regexprep(annotationNamesEdit, '\s+', '');
            merge = workflow.getNode('FeatureSetMerger');
            merge.getConfig()...
                .updateParamValue('AnnotationNamesForMerging', strsplit(annotationNamesEdit, ','));
            
            paramValue = get(app.PCANPMLabelViewEdit,'String');
            paramValue = strsplit(paramValue, ',');
            paramValueGroupList = regexprep(paramValue{1}, '\s+', '');
            paramValueLabelFormat = regexprep(paramValue{2}, '\s+', '');
            PcaFeatureMerger = workflow.getNode('ViewPcaFeatureMerger');
            PcaFeatureMerger.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{paramValueGroupList}, 'LabelFormat', {strcat(paramValueLabelFormat, ':([^_]*)')}}});
            
            
            %% Run
            workflow.run();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleOptimizedTab = uifigure('Visible', 'off');
            app.SimpleOptimizedTab.Name = 'PreProcessing';
            app.tab1Panel = uipanel(app.SimpleOptimizedTab);
            app.uipanel7 = uipanel(app.tab1Panel);
            app.text14 = uilabel(app.uipanel7);
            app.text14.Text = 'Feature preprocessing: positive, negative or both polarities';
            app.uipanel8 = uipanel(app.tab1Panel);
            app.uipanel8.Title = 'Input/output Files';
            app.MetadataFilePath = uibutton(app.uipanel8, 'push');
            app.MetadataFilePath.ButtonPushedFcn = createCallbackFcn(app, @MetadataFilePath_Callback, true);
            app.MetadataFilePath.Text = 'Browse for Metadata';
            app.InputPositiveFilePath = uibutton(app.uipanel8, 'push');
            app.InputPositiveFilePath.ButtonPushedFcn = createCallbackFcn(app, @InputPositiveFilePath_Callback, true);
            app.InputPositiveFilePath.Text = 'Browse for Positive InputFile';
            app.checkbox1 = uicheckbox(app.uipanel8);
            app.checkbox1.Text = 'Negative Mode';
            app.checkbox2 = uicheckbox(app.uipanel8);
            app.checkbox2.Text = 'Positive Mode';
            app.InputNegativeFilePath = uibutton(app.uipanel8, 'push');
            app.InputNegativeFilePath.ButtonPushedFcn = createCallbackFcn(app, @InputNegativeFilePath_Callback, true);
            app.InputNegativeFilePath.Text = 'Browse for Negative InputFile';
            app.OutputFilePath = uibutton(app.uipanel8, 'push');
            app.OutputFilePath.ButtonPushedFcn = createCallbackFcn(app, @OutputFilePath_Callback, true);
            app.OutputFilePath.Text = 'Browse for Output File';
            app.Reset = uibutton(app.tab1Panel, 'push');
            app.Reset.Text = 'Reset';
            app.uipanel28 = uipanel(app.tab1Panel);
            app.uipanel28.Title = 'Parameter: NegPosMerging';
            app.text67 = uilabel(app.uipanel28);
            app.text67.Text = 'AnnotationsNames';
            app.AnnotationNamesEdit = uieditfield(app.uipanel28, 'text');
            app.AnnotationNamesEdit.Value = 'Batch,SampleType';
            app.text68 = uilabel(app.uipanel28);
            app.text68.Text = 'LabelsViewPCA';
            app.PCANPMLabelViewEdit = uieditfield(app.uipanel28, 'text');
            app.PCANPMLabelViewEdit.Value = 'SampleType,SampleType';
            app.Process = uibutton(app.tab1Panel, 'push');
            app.Process.ButtonPushedFcn = createCallbackFcn(app, @Process_Callback, true);
            app.Process.Text = 'Process';
            app.tab1text = uilabel(app.SimpleOptimizedTab);
            app.tab1text.Text = 'Tab';
            app.tab2text = uilabel(app.SimpleOptimizedTab);
            app.tab2text.Text = 'Tab';
            app.tab2Panel = uipanel(app.SimpleOptimizedTab);
            app.uipanel19 = uipanel(app.tab2Panel);
            app.uipanel19.Title = '3-DataSelector';
            app.nListOfNames = uieditfield(app.uipanel19, 'text');
            app.text41 = uilabel(app.uipanel19);
            app.text41.Text = 'ListOfNames';
            app.uipanel21 = uipanel(app.tab2Panel);
            app.uipanel21.Title = '1-MetaTableParser';
            app.text46 = uilabel(app.uipanel21);
            app.text46.Text = 'Sheet';
            app.nSheetEdit = uieditfield(app.uipanel21, 'text');
            app.uipanel22 = uipanel(app.tab2Panel);
            app.uipanel22.Title = '4-BlankFilter';
            app.nBFLablesViewPCAEdit = uieditfield(app.uipanel22, 'text');
            app.nBFLablesViewPCAEdit.Value = 'SampleType,SampleType';
            app.text49 = uilabel(app.uipanel22);
            app.text49.Text = 'LabelsViewPCA';
            app.nBFLabelViewEdit = uieditfield(app.uipanel22, 'text');
            app.nBFLabelViewEdit.Value = 'UserID';
            app.text50 = uilabel(app.uipanel22);
            app.text50.Text = 'LabelsViewPlots';
            app.nThresholdBlankEdit = uieditfield(app.uipanel22, 'text');
            app.nThresholdBlankEdit.Value = '3';
            app.text51 = uilabel(app.uipanel22);
            app.text51.Text = 'Threshold';
            app.uipanel23 = uipanel(app.tab2Panel);
            app.uipanel23.Title = '5-PercentageRuleFilter';
            app.nPRFLAbelsViewPCAEdit = uieditfield(app.uipanel23, 'text');
            app.nPRFLAbelsViewPCAEdit.Value = 'SampleType, SampleType';
            app.text52 = uilabel(app.uipanel23);
            app.text52.Text = 'LabelsViewPCA';
            app.nPRFLAbelsViewPlotsEdit = uieditfield(app.uipanel23, 'text');
            app.nPRFLAbelsViewPlotsEdit.Value = 'SampleType';
            app.text53 = uilabel(app.uipanel23);
            app.text53.Text = 'LabelsViewPlots';
            app.nGroupListEdit = uieditfield(app.uipanel23, 'text');
            app.text54 = uilabel(app.uipanel23);
            app.text54.Text = 'GroupList';
            app.nThresholdPercRuleFilterEdit = uieditfield(app.uipanel23, 'text');
            app.nThresholdPercRuleFilterEdit.Value = '0.8';
            app.text55 = uilabel(app.uipanel23);
            app.text55.Text = 'Threshold';
            app.text56 = uilabel(app.uipanel23);
            app.text56.Text = 'MethodOfFiltering';
            app.nMethodOfFilteringEdit = uieditfield(app.uipanel23, 'text');
            app.uipanel24 = uipanel(app.tab2Panel);
            app.uipanel24.Title = '6-QcCV';
            app.nQcCVLabelsViewPCAEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVLabelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text57 = uilabel(app.uipanel24);
            app.text57.Text = 'LabelsViewPCA';
            app.nQcCVLabelsViewPlotsEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVLabelsViewPlotsEdit.Value = 'SampleType';
            app.text58 = uilabel(app.uipanel24);
            app.text58.Text = 'LabelsViewPlots';
            app.nQcCVLoqEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVLoqEdit.Value = '1e4';
            app.text59 = uilabel(app.uipanel24);
            app.text59.Text = 'LoQ';
            app.nQcCVThresholdEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVThresholdEdit.Value = '0.3';
            app.text60 = uilabel(app.uipanel24);
            app.text60.Text = 'Threshold';
            app.uipanel25 = uipanel(app.tab2Panel);
            app.uipanel25.Title = '7-IntraDriftCorrector';
            app.text63 = uilabel(app.uipanel25);
            app.text63.Text = 'LabelsViewPCA';
            app.nIDFLAbelsViewPCAEdit = uieditfield(app.uipanel25, 'text');
            app.nIDFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.uipanel26 = uipanel(app.tab2Panel);
            app.uipanel26.Title = '8-InterDriftCorrector';
            app.nIaDFLAbelsViewPCAEdit = uieditfield(app.uipanel26, 'text');
            app.nIaDFLAbelsViewPCAEdit.Value = 'SampleType, SampleType';
            app.text64 = uilabel(app.uipanel26);
            app.text64.Text = 'LabelsViewPCA';
            app.uipanel20 = uipanel(app.tab2Panel);
            app.uipanel20.Title = '2-ConsensusDataAnnotator';
            app.text42 = uilabel(app.uipanel20);
            app.text42.Text = 'HeadersToUse';
            app.nHeaderToUseEdit = uieditfield(app.uipanel20, 'text');
            app.nHeaderToUseEdit.Value = 'SequenceNumber,Batch,Polarity,UserID,SampleType';
            app.text43 = uilabel(app.uipanel20);
            app.text43.Text = 'UserIdHeader';
            app.nUserIDHeaderEdit = uieditfield(app.uipanel20, 'text');
            app.text44 = uilabel(app.uipanel20);
            app.text44.Text = 'LabelsViewPlots';
            app.nCDALabelViewEdit = uieditfield(app.uipanel20, 'text');
            app.nCDALabelViewEdit.Value = 'UserID';
            app.text45 = uilabel(app.uipanel20);
            app.text45.Text = 'LabelsViewPCA';
            app.nCDALabelsViewPCAEdit = uieditfield(app.uipanel20, 'text');
            app.nCDALabelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.tab3text = uilabel(app.SimpleOptimizedTab);
            app.tab3text.Text = 'Tab';
            app.tab3Panel = uipanel(app.SimpleOptimizedTab);
            app.uipanel29 = uipanel(app.tab3Panel);
            app.uipanel29.Title = '3-DataSelector';
            app.pListOfNames = uieditfield(app.uipanel29, 'text');
            app.text69 = uilabel(app.uipanel29);
            app.text69.Text = 'ListOfNames';
            app.uipanel30 = uipanel(app.tab3Panel);
            app.uipanel30.Title = '2-ConsensusDataAnnotator';
            app.text70 = uilabel(app.uipanel30);
            app.text70.Text = 'HeadersToUse';
            app.pHeaderToUseEdit = uieditfield(app.uipanel30, 'text');
            app.pHeaderToUseEdit.Value = 'SequenceNumber,Batch,Polarity,UserID,SampleType';
            app.text71 = uilabel(app.uipanel30);
            app.text71.Text = 'UserIdHeader';
            app.pUserIDHeaderEdit = uieditfield(app.uipanel30, 'text');
            app.text72 = uilabel(app.uipanel30);
            app.text72.Text = 'LabelsViewPlots';
            app.pCDALabelViewEdit = uieditfield(app.uipanel30, 'text');
            app.pCDALabelViewEdit.Value = 'UserID';
            app.text73 = uilabel(app.uipanel30);
            app.text73.Text = 'LabelsViewPCA';
            app.pCDALabelsViewPCAEdit = uieditfield(app.uipanel30, 'text');
            app.pCDALabelsViewPCAEdit.Value = 'SampleType, SampleType';
            app.uipanel31 = uipanel(app.tab3Panel);
            app.uipanel31.Title = '1-MetaTableParser';
            app.text74 = uilabel(app.uipanel31);
            app.text74.Text = 'Sheet';
            app.pSheetEdit = uieditfield(app.uipanel31, 'text');
            app.uipanel32 = uipanel(app.tab3Panel);
            app.uipanel32.Title = '4-BlankFilter';
            app.pBFLablesViewPCAEdit = uieditfield(app.uipanel32, 'text');
            app.pBFLablesViewPCAEdit.Value = 'SampleType, SampleType';
            app.text75 = uilabel(app.uipanel32);
            app.text75.Text = 'LabelsViewPCA';
            app.pBFLabelViewEdit = uieditfield(app.uipanel32, 'text');
            app.pBFLabelViewEdit.Value = 'UserID';
            app.text76 = uilabel(app.uipanel32);
            app.text76.Text = 'LabelsViewPlots';
            app.pThresholdBlankEdit = uieditfield(app.uipanel32, 'text');
            app.pThresholdBlankEdit.Value = '3';
            app.text77 = uilabel(app.uipanel32);
            app.text77.Text = 'Threshold';
            app.uipanel33 = uipanel(app.tab3Panel);
            app.uipanel33.Title = '5-PercentageRuleFilter';
            app.pPRFLAbelsViewPCAEdit = uieditfield(app.uipanel33, 'text');
            app.pPRFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text78 = uilabel(app.uipanel33);
            app.text78.Text = 'LabelsViewPCA';
            app.pPRFLAbelsViewPlotsEdit = uieditfield(app.uipanel33, 'text');
            app.pPRFLAbelsViewPlotsEdit.Value = 'SampleType';
            app.text79 = uilabel(app.uipanel33);
            app.text79.Text = 'LabelsViewPlots';
            app.pGroupListEdit = uieditfield(app.uipanel33, 'text');
            app.text80 = uilabel(app.uipanel33);
            app.text80.Text = 'GroupList';
            app.pThresholdPercRuleFilterEdit = uieditfield(app.uipanel33, 'text');
            app.pThresholdPercRuleFilterEdit.Value = '0.8';
            app.text81 = uilabel(app.uipanel33);
            app.text81.Text = 'Threshold';
            app.text82 = uilabel(app.uipanel33);
            app.text82.Text = 'MethodOfFiltering';
            app.pMethodOfFilteringEdit = uieditfield(app.uipanel33, 'text');
            app.uipanel34 = uipanel(app.tab3Panel);
            app.uipanel34.Title = '6-QcCV';
            app.pQcCVLabelsViewPCAEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVLabelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text83 = uilabel(app.uipanel34);
            app.text83.Text = 'LabelsViewPCA';
            app.pQcCVLabelsViewPlotsEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVLabelsViewPlotsEdit.Value = 'SampleType';
            app.text84 = uilabel(app.uipanel34);
            app.text84.Text = 'LabelsViewPlots';
            app.pQcCVLoqEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVLoqEdit.Value = '1e4';
            app.text85 = uilabel(app.uipanel34);
            app.text85.Text = 'LoQ';
            app.pQcCVThresholdEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVThresholdEdit.Value = '0.3';
            app.text86 = uilabel(app.uipanel34);
            app.text86.Text = 'Threshold';
            app.uipanel35 = uipanel(app.tab3Panel);
            app.uipanel35.Title = '7-IntraDriftCorrector';
            app.text87 = uilabel(app.uipanel35);
            app.text87.Text = 'LabelsViewPCA';
            app.pIDFLAbelsViewPCAEdit = uieditfield(app.uipanel35, 'text');
            app.pIDFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.uipanel36 = uipanel(app.tab3Panel);
            app.uipanel36.Title = '8-InterDriftCorrector';
            app.pIaDFLAbelsViewPCAEdit = uieditfield(app.uipanel36, 'text');
            app.pIaDFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text88 = uilabel(app.uipanel36);
            app.text88.Text = 'LabelsViewPCA';
            % Show the figure after all components are created
            app.SimpleOptimizedTab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcessing_App_old

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleOptimizedTab)

            % Execute the startup function
            runStartupFcn(app, @PreProcessing_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleOptimizedTab)
        end
    end
end