classdef LipidMatch_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        uipanel1                 matlab.ui.container.Panel
        reset                    matlab.ui.control.Button
        uipanel2                 matlab.ui.container.Panel
        text5                    matlab.ui.control.Label
        FileNameFilterPosEdit    matlab.ui.control.EditField
        text6                    matlab.ui.control.Label
        FileExtensionFilterEdit  matlab.ui.control.EditField
        text9                    matlab.ui.control.Label
        FileNameFilterNegEdit    matlab.ui.control.EditField
        identify                 matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        ViewlogButton            matlab.ui.control.Button
        uipanel5                 matlab.ui.container.Panel
        text8                    matlab.ui.control.Label
        uipanel6                 matlab.ui.container.Panel
        FeatureTable             matlab.ui.control.Button
        OuputFilePath            matlab.ui.control.Button
        posRawFiles              matlab.ui.control.Button
        rawNegFiles              matlab.ui.control.Button
        Input_RawNeg             matlab.ui.control.EditField
        Output                   matlab.ui.control.EditField
        Input_FeatureTable       matlab.ui.control.EditField
        Input_RawPos             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LipidMatch_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to LipidMatch (see VARARGIN)
            %
            %             % Choose default command line output for LipidMatch
            %             app.output = hObject;
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
        end

        % Button pushed function: FeatureTable
        function FeatureTable_Callback(app, event)
            % hObject    handle to FeatureTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            commandwindow()
            [featureData, path] = uigetfile('*.csv');
            if ~isempty(featureData) && ~isnumeric(featureData)
                featureDataPath = [path featureData];
                app.Input_FeatureTable.Value= featureDataPath;
                setappdata(0,'featureDatapath', featureDataPath);
            end
        end

        % Button pushed function: OuputFilePath
        function OuputFilePath_Callback(app, event)
            % hObject    handle to OuputFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            commandwindow()
            outFolder = uigetdir();
            if ~isempty(outFolder) && ~isnumeric(outFolder)
                app.Output.Value= outFolder;
                setappdata(0,'outputdatapath', outFolder);
            end
        end

        % Button pushed function: identify
        function identify_Callback(app, event)
            % hObject    handle to identify (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %% Identification
            app.Lamp.Color= 'red';
            lipidWorkflow = bioapps.lipidmatch.model.LipidMatchWorkflow();
            lipidWorkflow.getConfig()...
                .updateParamValue('WorkingDirectory',getappdata(0, 'outputdatapath') );
            
            mzFileImporter = lipidWorkflow.getNode('NegRawFileImporter');
            
            
            fileExtensionFilterEdit = get(app.FileExtensionFilterEdit,'String');
            
            fileNameFilterNegEdit = get(app.FileNameFilterNegEdit,'String');
            mzNeg = getappdata(0, 'inputNegRawDatapath');
            for i=1:length(mzNeg)
                mzFileImporter.addInputFilePath( mzNeg{i} );
            end
            mzFileImporter.getConfig()...
                .updateParamValue('FileNameFilter', fileNameFilterNegEdit)...
                .updateParamValue('FileExtensionFilter', fileExtensionFilterEdit);
            
            zFileImporter = lipidWorkflow.getNode('PosRawFileImporter');
            fileNameFilterPosEdit = get(app.FileNameFilterPosEdit,'String');
            
            mzPos = getappdata(0, 'inputPosRawDatapath');
            for i=1:length(mzPos)
                zFileImporter.addInputFilePath( mzPos{i} );
            end
            zFileImporter.getConfig()...
                .updateParamValue('FileNameFilter', fileNameFilterEdit)...
                .updateParamValue('FileExtensionFilter', fileNameFilterPosEdit);
            
            featureTable = lipidWorkflow.getNode('FeatureTableImporter');
            featureTable.addInputFilePath( getappdata(0, 'featureDatapath') );
            
            lipidWorkflow.run();
            if lipidWorkflow.isEnded
                app.Lamp.Color= 'green';
            end
        end

        % Button pushed function: posRawFiles
        function posRawFiles_Callback(app, event)
            % hObject    handle to posRawFiles (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            commandwindow()
            inFolder = uipickfiles ;
            if ~isempty(inFolder) && ~isnumeric(inFolder)
                app.Input_RawPos.Value= char(join(inFolder, ','));
                setappdata(0,'inputPosRawDatapath', inFolder);
            end
        end

        % Button pushed function: rawNegFiles
        function rawNegFiles_Callback(app, event)
            % hObject    handle to rawNegFiles (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            commandwindow()
            inFolder = uipickfiles;
            if ~isempty(inFolder) && ~isnumeric(inFolder)
                app.Input_RawNeg.Value= char(join(inFolder, ','));
                setappdata(0,'inputNegRawDatapath', inFolder);
            end
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            close(gcbf)
            LipidMatch_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'LipidMatch';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Parameter';
            app.reset = uibutton(app.uipanel1, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'Reset';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'File Import Filters';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = 'FileNameFilterPositive';
            app.FileNameFilterPosEdit = uieditfield(app.uipanel2, 'text');
            app.FileNameFilterPosEdit.Tooltip = {'The pattern used to select the positive raw files containing the fragmentation to be used for identifiaction'};
            app.FileNameFilterPosEdit.Value = 'QC';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'FileExtensionFilter';
            app.FileExtensionFilterEdit = uieditfield(app.uipanel2, 'text');
            app.FileExtensionFilterEdit.Tooltip = {'The extension of the raw files (raw '; ' d etc...)'};
            app.FileExtensionFilterEdit.Value = 'raw';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Text = 'FileNameFilterNegative';
            app.FileNameFilterNegEdit = uieditfield(app.uipanel2, 'text');
            app.FileNameFilterNegEdit.Tooltip = {'The pattern used to select the negative raw files containing the fragmentation to be used for identifiaction'};
            app.FileNameFilterNegEdit.Value = 'QC';
            app.identify = uibutton(app.uipanel1, 'push');
            app.identify.ButtonPushedFcn = createCallbackFcn(app, @identify_Callback, true);
            app.identify.Tooltip = {'Identification of the features using lipidmatch'};
            app.identify.Text = 'Identify';
            app.Lamp = uilamp(app.uipanel1);
            app.ViewlogButton = uibutton(app.uipanel1, 'push');
            app.ViewlogButton.Text = 'View log';
            app.uipanel5 = uipanel(app.figure1);
            app.text8 = uilabel(app.uipanel5);
            app.text8.Text = 'Identifictaion of features using LipidMatch';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Input/Output Files';
            app.FeatureTable = uibutton(app.uipanel6, 'push');
            app.FeatureTable.ButtonPushedFcn = createCallbackFcn(app, @FeatureTable_Callback, true);
            app.FeatureTable.Text = 'Browse for feature table file path ';
            app.OuputFilePath = uibutton(app.uipanel6, 'push');
            app.OuputFilePath.ButtonPushedFcn = createCallbackFcn(app, @OuputFilePath_Callback, true);
            app.OuputFilePath.Text = 'Browse for output file path';
            app.posRawFiles = uibutton(app.uipanel6, 'push');
            app.posRawFiles.ButtonPushedFcn = createCallbackFcn(app, @posRawFiles_Callback, true);
            app.posRawFiles.Text = 'Browse for path of positive raw files';
            app.rawNegFiles = uibutton(app.uipanel6, 'push');
            app.rawNegFiles.ButtonPushedFcn = createCallbackFcn(app, @rawNegFiles_Callback, true);
            app.rawNegFiles.Text = 'Browse for path of neagtive raw files';
            app.Input_RawNeg = uieditfield(app.uipanel6, 'text');
            app.Input_RawNeg.Editable = 'off';
            app.Output = uieditfield(app.uipanel6, 'text');
            app.Output.Editable = 'off';
            app.Input_FeatureTable = uieditfield(app.uipanel6, 'text');
            app.Input_FeatureTable.Editable = 'off';
            app.Input_RawPos = uieditfield(app.uipanel6, 'text');
            app.Input_RawPos.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LipidMatch_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @LipidMatch_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end