classdef PreProcessing_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        uipanel7                        matlab.ui.container.Panel
        text14                          matlab.ui.control.Label
        uipanel8                        matlab.ui.container.Panel
        MetadataFilePath                matlab.ui.control.Button
        InputPositiveFilePath           matlab.ui.control.Button
        InputNegativeFilePath           matlab.ui.control.Button
        OutputFilePath                  matlab.ui.control.Button
        EditField_Meta                  matlab.ui.control.EditField
        EditField_Pos                   matlab.ui.control.EditField
        EditField_Neg                   matlab.ui.control.EditField
        EditField_Out                   matlab.ui.control.EditField
        uipanel28                       matlab.ui.container.Panel
        text67                          matlab.ui.control.Label
        AnnotationNamesEdit             matlab.ui.control.EditField
        text68                          matlab.ui.control.Label
        PCANPMLabelViewEdit             matlab.ui.control.EditField
        Reset                           matlab.ui.control.Button
        Process                         matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ViewlogButton                   matlab.ui.control.Button
        NegativemodeTab                 matlab.ui.container.Tab
        uipanel19                       matlab.ui.container.Panel
        nListOfNames                    matlab.ui.control.EditField
        text41                          matlab.ui.control.Label
        uipanel21                       matlab.ui.container.Panel
        text46                          matlab.ui.control.Label
        nSheetEdit                      matlab.ui.control.EditField
        uipanel22                       matlab.ui.container.Panel
        nBFLablesViewPCAEdit            matlab.ui.control.EditField
        text49                          matlab.ui.control.Label
        nBFLabelViewEdit                matlab.ui.control.EditField
        text50                          matlab.ui.control.Label
        nThresholdBlankEdit             matlab.ui.control.EditField
        text51                          matlab.ui.control.Label
        uipanel23                       matlab.ui.container.Panel
        nPRFLAbelsViewPCAEdit           matlab.ui.control.EditField
        text52                          matlab.ui.control.Label
        nPRFLAbelsViewPlotsEdit         matlab.ui.control.EditField
        text53                          matlab.ui.control.Label
        nGroupListEdit                  matlab.ui.control.EditField
        text54                          matlab.ui.control.Label
        nThresholdPercRuleFilterEdit    matlab.ui.control.EditField
        text55                          matlab.ui.control.Label
        text56                          matlab.ui.control.Label
        nMethodOfFilteringEdit          matlab.ui.control.EditField
        uipanel24                       matlab.ui.container.Panel
        nQcCVLabelsViewPCAEdit          matlab.ui.control.EditField
        text57                          matlab.ui.control.Label
        nQcCVLabelsViewPlotsEdit        matlab.ui.control.EditField
        text58                          matlab.ui.control.Label
        nQcCVLoqEdit                    matlab.ui.control.EditField
        text59                          matlab.ui.control.Label
        nQcCVThresholdEdit              matlab.ui.control.EditField
        text60                          matlab.ui.control.Label
        uipanel25                       matlab.ui.container.Panel
        text63                          matlab.ui.control.Label
        nIDFLAbelsViewPCAEdit           matlab.ui.control.EditField
        uipanel26                       matlab.ui.container.Panel
        nIaDFLAbelsViewPCAEdit          matlab.ui.control.EditField
        text64                          matlab.ui.control.Label
        uipanel20                       matlab.ui.container.Panel
        text42                          matlab.ui.control.Label
        nHeaderToUseEdit                matlab.ui.control.EditField
        text43                          matlab.ui.control.Label
        nUserIDHeaderEdit               matlab.ui.control.EditField
        text44                          matlab.ui.control.Label
        nCDALabelViewEdit               matlab.ui.control.EditField
        text45                          matlab.ui.control.Label
        nCDALabelsViewPCAEdit           matlab.ui.control.EditField
        ActivateCheckBoxInterDriftNeg   matlab.ui.control.CheckBox
        ActivateCheckBoxIntraDriftNeg   matlab.ui.control.CheckBox
        ActivateCheckBoxQcCVNeg         matlab.ui.control.CheckBox
        ActivateCheckBoxPercentageFilterNeg  matlab.ui.control.CheckBox
        ActivateCheckBoxBlankFilterNeg  matlab.ui.control.CheckBox
        PositivemodeTab                 matlab.ui.container.Tab
        uipanel29                       matlab.ui.container.Panel
        pListOfNames                    matlab.ui.control.EditField
        text69                          matlab.ui.control.Label
        uipanel30                       matlab.ui.container.Panel
        text70                          matlab.ui.control.Label
        pHeaderToUseEdit                matlab.ui.control.EditField
        text71                          matlab.ui.control.Label
        pUserIDHeaderEdit               matlab.ui.control.EditField
        text72                          matlab.ui.control.Label
        pCDALabelViewEdit               matlab.ui.control.EditField
        text73                          matlab.ui.control.Label
        pCDALabelsViewPCAEdit           matlab.ui.control.EditField
        uipanel31                       matlab.ui.container.Panel
        text74                          matlab.ui.control.Label
        pSheetEdit                      matlab.ui.control.EditField
        uipanel32                       matlab.ui.container.Panel
        pBFLablesViewPCAEdit            matlab.ui.control.EditField
        text75                          matlab.ui.control.Label
        pBFLabelViewEdit                matlab.ui.control.EditField
        text76                          matlab.ui.control.Label
        pThresholdBlankEdit             matlab.ui.control.EditField
        text77                          matlab.ui.control.Label
        uipanel33                       matlab.ui.container.Panel
        pPRFLAbelsViewPCAEdit           matlab.ui.control.EditField
        text78                          matlab.ui.control.Label
        pPRFLAbelsViewPlotsEdit         matlab.ui.control.EditField
        text79                          matlab.ui.control.Label
        pGroupListEdit                  matlab.ui.control.EditField
        text80                          matlab.ui.control.Label
        pThresholdPercRuleFilterEdit    matlab.ui.control.EditField
        text81                          matlab.ui.control.Label
        text82                          matlab.ui.control.Label
        pMethodOfFilteringEdit          matlab.ui.control.EditField
        uipanel34                       matlab.ui.container.Panel
        pQcCVLabelsViewPCAEdit          matlab.ui.control.EditField
        text83                          matlab.ui.control.Label
        pQcCVLabelsViewPlotsEdit        matlab.ui.control.EditField
        text84                          matlab.ui.control.Label
        pQcCVLoqEdit                    matlab.ui.control.EditField
        text85                          matlab.ui.control.Label
        pQcCVThresholdEdit              matlab.ui.control.EditField
        text86                          matlab.ui.control.Label
        uipanel35                       matlab.ui.container.Panel
        text87                          matlab.ui.control.Label
        pIDFLAbelsViewPCAEdit           matlab.ui.control.EditField
        uipanel36                       matlab.ui.container.Panel
        pIaDFLAbelsViewPCAEdit          matlab.ui.control.EditField
        text88                          matlab.ui.control.Label
        ActivateCheckBoxInterDriftPos   matlab.ui.control.CheckBox
        ActivateCheckBoxIntraDriftPos   matlab.ui.control.CheckBox
        ActivateCheckBoxQcCVPos         matlab.ui.control.CheckBox
        ActivateCheckBoxPercentageFilterPos  matlab.ui.control.CheckBox
        ActivateCheckBoxBlankFilterPos  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: InputPositiveFilePath
        function InputPositiveFilePathButtonPushed(app, event)
            inPosFolder = uigetdir();
            app.PreprocessingUIFigure.Visible = 'off';
            app.PreprocessingUIFigure.Visible = 'on';
            if ~isequal(inPosFolder, 0)
                app.EditField_Pos.Value= inPosFolder;
                setappdata(0,'inputPosDatapath', inPosFolder);
            end
        end

        % Button pushed function: MetadataFilePath
        function InputMetaDataFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            app.PreprocessingUIFigure.Visible = 'off';
            app.PreprocessingUIFigure.Visible = 'on';
            if ~isequal(file, 0)
                MetadataPath = [path file];
                app.EditField_Meta.Value= MetadataPath;
                setappdata(0,'metaDatapath', MetadataPath);
            end
        end

        % Button pushed function: InputNegativeFilePath
        function InputNegativeFilePathButtonPushed(app, event)
            inNegFolder = uigetdir();
            app.PreprocessingUIFigure.Visible = 'off';
            app.PreprocessingUIFigure.Visible = 'on';
            if ~isempty(inNegFolder) && ~isnumeric(inNegFolder)
                app.EditField_Neg.Value= inNegFolder;
                setappdata(0,'inputNegDatapath', inNegFolder);
            end
        end

        % Button pushed function: OutputFilePath
        function OutputFilePathButtonPushed(app, event)
            outFolder = uigetdir();
            app.PreprocessingUIFigure.Visible = 'off';
            app.PreprocessingUIFigure.Visible = 'on';
            if ~isequal(outFolder, 0)
                app.EditField_Out.Value= outFolder;
                setappdata(0,'outputdatapath', outFolder);
            end
        end

        % Button pushed function: Process
        function ProcessButtonPushed(app, event)
            
            app.Lamp.Color= 'red';
            diary(fullfile(getappdata(0, 'outputdatapath'), '/log.txt'));
            workflow = biotracs.mimosa.model.PreprocessingWorkflow();
            workflow.getConfig()...
                .updateParamValue( 'WorkingDirectory',  getappdata(0, 'outputdatapath') );
            
            %% Import SampleMetaData
            inputAdpter = workflow.getNode('SampleMetaDataFileImporter');
            inputAdpter.addInputFilePath(  getappdata(0, 'metaDatapath'));
            
            %% Import FeatureSet
            pinputAdpter = workflow.getNode('PosConsensusFileImporter');
            if ~isempty(getappdata(0, 'inputPosDatapath'))
                pinputAdpter.addInputFilePath(  getappdata(0, 'inputPosDatapath'));
                pinputAdpter.getConfig()...
                    .updateParamValue( 'FileExtensionFilter', 'csv');
            end
            % ConsensusDataParser
            w1 = workflow.getNode('PosModePreprocessingWorkflow');
            
            psheetEdit = app.pSheetEdit.Value;
            
            pmetaTableParser = w1.getNode('MetaTableParser');
            pmetaTableParser.getConfig()...
                .updateParamValue('Sheet', psheetEdit);
            
            pconsensusDataExtractor = w1.getNode('ConsensusDataExtractor');
            pconsensusDataExtractor.getConfig()...
                .updateParamValue('Polarity', 'Pos');
            
            pHeaderToUseValue = app.pHeaderToUseEdit.Value;
            pHeaderToUseValue= regexprep(pHeaderToUseValue, '\s+', '');
            
            pUserIDHeaderValue = app.pUserIDHeaderEdit.Value;
            
            % Annotate
            pconsensusDataAnnotator = w1.getNode('ConsensusDataAnnotator');
            pconsensusDataAnnotator.getConfig()...
                .updateParamValue('HeadersToUse', strsplit(pHeaderToUseValue, ','))...
                .updateParamValue('UserIdHeader', pUserIDHeaderValue);


            pparamValue = app.pCDALabelViewEdit.Value;
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewConsensusDataAnnotator = w1.getNode('ViewConsensusDataAnnotator');
            pviewConsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = app.pCDALabelsViewPCAEdit.Value;
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCAconsensusDataAnnotator = w1.getNode('ViewPcaConsensusDataAnnotator');
            pPCAconsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % DataRemove
            pListOfNamesValue = app.pListOfNames.Value;
            pListOfNamesValue = regexprep(pListOfNamesValue, '\s+', '');
            pdataSelector = w1.getNode('DataSelector');
            pdataSelector.getConfig().updateParamValue(...
                'ListOfNames', strsplit(pListOfNamesValue, ','));
            
            % Blank Filter
            pThresholdBlankValue = app.pThresholdBlankEdit.Value;
            activateStatusBFp = app.ActivateCheckBoxBlankFilterNeg.Value;
            pThresholdBlankValue = str2num(pThresholdBlankValue);
            if activateStatusBFp == 1
   
                pblankFilter = w1.getNode('BlankFilter');
                pblankFilter.getConfig()...
                    .updateParamValue('BlankIntensityRatio', pThresholdBlankValue);
            else
                pblankFilter = w1.getNode('BlankFilter');
                pblankFilter.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            
                pparamValue = app.pBFLabelViewEdit.Value;
                pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
                pviewBlankFilter = w1.getNode('ViewBlankFilter');
                pviewBlankFilter.getConfig()...
                    .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
                pparamValue = app.pBFLablesViewPCAEdit.Value;
                pparamValue = strsplit(pparamValue, ',');
                pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
                pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
                pPCABlankFilter = w1.getNode('ViewPcaBlankFilter');
                pPCABlankFilter.getConfig()...
                    .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
    
            % Filter by 80 %
            pThresholdPercRuleFilterValue = app.pThresholdPercRuleFilterEdit.Value;
            pThresholdPercRuleFilterValue = str2num(pThresholdPercRuleFilterValue);
            pGroupListValue = app.pGroupListEdit.Value;
            pGroupListValue =regexprep(pGroupListValue, '\s+', '');
            pMethodOfFilteringValue = app.pMethodOfFilteringEdit.Value;
            statusPfp = app.ActivateCheckBoxPercentageFilterPos.Value;
            
            if statusPfp == 1
                ppercentageRuleFilter = w1.getNode('PercentageRuleFilter');
                ppercentageRuleFilter.getConfig()...
                    .updateParamValue('PercentageRuleThreshold', pThresholdPercRuleFilterValue)...
                    .updateParamValue('GroupList',strsplit(pGroupListValue, ',') ) ...
                    .updateParamValue('MethodOfFiltering', pMethodOfFilteringValue);
            else
                ppercentageRuleFilter = w1.getNode('PercentageRuleFilter');
                ppercentageRuleFilter.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            
            pparamValue = app.pPRFLAbelsViewPlotsEdit.Value;
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewThresholdPercRuleFilter = w1.getNode('ViewPercentageRuleFilter');
            pviewThresholdPercRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = app.pPRFLAbelsViewPCAEdit.Value;
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCAThresholdPercRuleFilter = w1.getNode('ViewPcaPercentageRuleFilter');
            pPCAThresholdPercRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % FilterCv 20%
            pQcCVThresholdValue = app.pQcCVThresholdEdit.Value;
            pQcCVThresholdValue = str2num(pQcCVThresholdValue);
            pQcCVLoqValue = app.pQcCVLoqEdit.Value;
            pQcCVLoqValue = str2num(pQcCVLoqValue);
            statusQcCVp = app.ActivateCheckBoxQcCVPos.Value;
            
            if statusQcCVp ==1
                pqcCvFilter = w1.getNode('QcCvFilter');
                pqcCvFilter.getConfig()...
                .updateParamValue('QcCvThreshold', pQcCVThresholdValue)...
                .updateParamValue('PercentageRuleThreshold', 0)...
                .updateParamValue('LoQ', pQcCVLoqValue);
            else
                pqcCvFilter = w1.getNode('QcCvFilter');
                pqcCvFilter.getConfig()...
                    .updateParamValue('IsPhantom', true);
                
            end
            
            pparamValue = app.pQcCVLabelsViewPlotsEdit.Value;
            pparamValueLabelFormat = regexprep(pparamValue, '\s+', '');
            pviewQcCV = w1.getNode('ViewQcCvFilter');
            pviewQcCV.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            pparamValue = app.pQcCVLabelsViewPCAEdit.Value;
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPCAQcCV = w1.getNode('ViewPcaQcCvFilter');
            pPCAQcCV.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});

            statusIntrap = app.ActivateCheckBoxIntraDriftPos.Value;
            
            if statusIntrap==0
                intraBatchDriftCorrectorp = w1.getNode('IntraBatchDriftCorrector');
                intraBatchDriftCorrectorp.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            
            pparamValue = app.pIaDFLAbelsViewPCAEdit.Value;
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPcaIntraBatchDriftCorrector = w1.getNode('ViewPcaIntraBatchDriftCorrector');
            pPcaIntraBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
             statusInterp = app.ActivateCheckBoxInterDriftPos.Value;
            
            if statusInterp==0
                interBatchDriftCorrectorp = w1.getNode('InterBatchDriftCorrector');
                interBatchDriftCorrectorp.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            pparamValue = app.pIDFLAbelsViewPCAEdit.Value;
            pparamValue = strsplit(pparamValue, ',');
            pparamValueGroupList = regexprep(pparamValue{1}, '\s+', '');
            pparamValueLabelFormat = regexprep(pparamValue{2}, '\s+', '');
            pPcaInterBatchDriftCorrector = w1.getNode('ViewPcaInterBatchDriftCorrector');
            pPcaInterBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{pparamValueGroupList}, 'LabelFormat', {strcat(pparamValueLabelFormat, ':([^_]*)')}}});
            
            
            %% Negative Workflow
            
            % Import FeatureSet
            ninputAdpter = workflow.getNode('NegConsensusFileImporter');
            if ~isempty(getappdata(0, 'inputNegDatapath'))
                
                ninputAdpter.addInputFilePath( getappdata(0, 'inputNegDatapath'));
                ninputAdpter.getConfig()...
                    .updateParamValue( 'FileExtensionFilter', 'csv');
            end
            % ConsensusDataParser
            w2 = workflow.getNode('NegModePreprocessingWorkflow');
            
            nsheetEdit = app.nSheetEdit.Value;
            
            nmetaTableParser = w2.getNode('MetaTableParser');
            nmetaTableParser.getConfig()...
                .updateParamValue('Sheet', nsheetEdit);
            
            nconsensusDataExtractor = w2.getNode('ConsensusDataExtractor');
            nconsensusDataExtractor.getConfig()...
                .updateParamValue('Polarity', 'Neg');
            
            nHeaderToUseValue = app.nHeaderToUseEdit.Value;
            nHeaderToUseValue= regexprep(nHeaderToUseValue, '\s+', '');
            nUserIDHeaderValue = app.nUserIDHeaderEdit.Value;
            
            
            % Annotate
            nconsensusDataAnnotator = w2.getNode('ConsensusDataAnnotator');
            nconsensusDataAnnotator.getConfig()...
                .updateParamValue('HeadersToUse', strsplit(nHeaderToUseValue, ','))...
                .updateParamValue('UserIdHeader',nUserIDHeaderValue);
            
            
            nparamValue = app.nCDALabelViewEdit.Value;
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewConsensusDataAnnotator = w2.getNode('ViewConsensusDataAnnotator');
            nviewConsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = app.nCDALabelsViewPCAEdit.Value;
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPCAconsensusDataAnnotator = w2.getNode('ViewPcaConsensusDataAnnotator');
            nPCAconsensusDataAnnotator.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            
            % DataRemove
            nListOfNamesValue = app.nListOfNames.Value;
            nListOfNamesValue = regexprep(nListOfNamesValue, '\s+', '');
            ndataSelector = w2.getNode('DataSelector');
            ndataSelector.getConfig().updateParamValue(...
                'ListOfNames', strsplit(nListOfNamesValue, ','));
            
            % Blank Filter
            nThresholdBlankValue = app.nThresholdBlankEdit.Value;
            nThresholdBlankValue = str2num(nThresholdBlankValue);
            activateStatusBF= app.ActivateCheckBoxBlankFilterNeg.Value;
            if activateStatusBF == 1
                nblankFilter = w2.getNode('BlankFilter');
                nblankFilter.getConfig()...
                    .updateParamValue('BlankIntensityRatio', nThresholdBlankValue);
            else
                nblankFilter = w2.getNode('BlankFilter');
                nblankFilter.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
                
                nparamValue = app.nBFLabelViewEdit.Value;
                nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
                nviewBlankFilter = w2.getNode('ViewBlankFilter');
                nviewBlankFilter.getConfig()...
                    .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
                nparamValue = app.nBFLablesViewPCAEdit.Value;
                nparamValue = strsplit(nparamValue, ',');
                nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
                nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
                nPcaBlankFilter = w2.getNode('ViewPcaBlankFilter');
                nPcaBlankFilter.getConfig()...
                    .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
           
            
            % Filter by 80 %
            nThresholdPercRuleFilterValue= app.nThresholdPercRuleFilterEdit.Value;
            nThresholdPercRuleFilterValue = str2num(nThresholdPercRuleFilterValue);
            nGroupListValue = app.nGroupListEdit.Value;
            nGroupListValue = regexprep(nGroupListValue, '\s+', '');
            nMethodOfFilteringValue = app.nMethodOfFilteringEdit.Value;
            activateStatusPF= app.ActivateCheckBoxPercentageFilterNeg.Value;
            
            if activateStatusPF == 1
            
            npercentageRuleFilter = w2.getNode('PercentageRuleFilter');
            npercentageRuleFilter.getConfig()...
                .updateParamValue('PercentageRuleThreshold', nThresholdPercRuleFilterValue)...
                .updateParamValue('GroupList', strsplit(nGroupListValue, ',')) ...
                .updateParamValue('MethodOfFiltering', nMethodOfFilteringValue);
            else 
             npercentageRuleFilter = w2.getNode('PercentageRuleFilter');
            npercentageRuleFilter.getConfig()...
                .updateParamValue('IsPhantom', true);
            end
            nparamValue = app.nPRFLAbelsViewPlotsEdit.Value;
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewPercentageRuleFilter = w2.getNode('ViewPercentageRuleFilter');
            nviewPercentageRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = app.nPRFLAbelsViewPCAEdit.Value;
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaPercentageRuleFilter = w2.getNode('ViewPcaPercentageRuleFilter');
            nPcaPercentageRuleFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
       
            
            % FilterCv 20%
            nQcCVThresholdValue = app.nQcCVThresholdEdit.Value;
            nQcCVThresholdValue = str2num(nQcCVThresholdValue);
            nQcCVLoqValue = app.nQcCVLoqEdit.Value;
            nQcCVLoqValue = str2num(nQcCVLoqValue);
            statusQcCV = app.ActivateCheckBoxQcCVNeg.Value;
            
            if statusQcCV== 1
                nqcCvFilter = w2.getNode('QcCvFilter');
                nqcCvFilter.getConfig()...
                    .updateParamValue('QcCvThreshold', nQcCVThresholdValue)...
                    .updateParamValue('PercentageRuleThreshold', 0)...
                    .updateParamValue('LoQ', nQcCVLoqValue);
            else
                nqcCvFilter = w2.getNode('QcCvFilter');
                nqcCvFilter.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            
            nparamValue = app.nQcCVLabelsViewPlotsEdit.Value;
            nparamValueLabelFormat = regexprep(nparamValue, '\s+', '');
            nviewQcCvFilter = w2.getNode('ViewQcCvFilter');
            nviewQcCvFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            nparamValue = app.nQcCVLabelsViewPCAEdit.Value;
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaQcCvFilter = w2.getNode('ViewPcaQcCvFilter');
            nPcaQcCvFilter.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            statusIntra = app.ActivateCheckBoxIntraDriftNeg.Value;
            if statusIntra ==0
                intradrift = w2.getNode('IntraBatchDriftCorrector');
                intradrift.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            nparamValue = app.nIaDFLAbelsViewPCAEdit.Value;
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaIntraBatchDriftCorrector = w2.getNode('ViewPcaIntraBatchDriftCorrector');
            nPcaIntraBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            statusInter = app.ActivateCheckBoxInterDriftNeg.Value;
            if statusInter ==0
                interdrift = w2.getNode('InterBatchDriftCorrector');
                interdrift.getConfig()...
                    .updateParamValue('IsPhantom', true);
            end
            
            nparamValue = app.nIDFLAbelsViewPCAEdit.Value;
            nparamValue = strsplit(nparamValue, ',');
            nparamValueGroupList = regexprep(nparamValue{1}, '\s+', '');
            nparamValueLabelFormat = regexprep(nparamValue{2}, '\s+', '');
            nPcaInterBatchDriftCorrector = w2.getNode('ViewPcaInterBatchDriftCorrector');
            nPcaInterBatchDriftCorrector.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{nparamValueGroupList}, 'LabelFormat', {strcat(nparamValueLabelFormat, ':([^_]*)')}}});
            
            annotationNamesEdit = app.AnnotationNamesEdit.Value;
            annotationNamesEdit=regexprep(annotationNamesEdit, '\s+', '');
            merge = workflow.getNode('FeatureSetMerger');
            merge.getConfig()...
                .updateParamValue('AnnotationNamesForMerging', strsplit(annotationNamesEdit, ','));
            
            paramValue = app.PCANPMLabelViewEdit.Value;
            paramValue = strsplit(paramValue, ',');
            paramValueGroupList = regexprep(paramValue{1}, '\s+', '');
            paramValueLabelFormat = regexprep(paramValue{2}, '\s+', '');
            PcaFeatureMerger = workflow.getNode('ViewPcaFeatureMerger');
            PcaFeatureMerger.getConfig()...
                .updateParamValue('ViewParameters', {{'GroupList',{paramValueGroupList}, 'LabelFormat', {strcat(paramValueLabelFormat, ':([^_]*)')}}});
            
            
            %% Run
            workflow.run();
            
            if workflow.isEnded
                app.Lamp.Color= 'green';
            end
            diary off;
        end

        % Button pushed function: ViewlogButton
        function ViewlogButtonPushed(app, event)
            outdir = getappdata(0, 'outputdatapath');
            system(['notepad ' fullfile(outdir,'/log.txt')]);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            close(gcbf)
            PreProcessing_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingUIFigure = uifigure('Visible', 'off');
            app.PreprocessingUIFigure.Name = 'Preprocessing';
            app.TabGroup = uitabgroup(app.PreprocessingUIFigure);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.uipanel7 = uipanel(app.PreprocessingTab);
            app.text14 = uilabel(app.uipanel7);
            app.text14.Text = 'Feature preprocessing: positive, negative or both polarities';
            app.uipanel8 = uipanel(app.PreprocessingTab);
            app.uipanel8.Title = 'Input/output Files';
            app.MetadataFilePath = uibutton(app.uipanel8, 'push');
            app.MetadataFilePath.ButtonPushedFcn = createCallbackFcn(app, @InputMetaDataFileButtonPushed, true);
            app.MetadataFilePath.Text = 'Browse for metadata file path';
            app.InputPositiveFilePath = uibutton(app.uipanel8, 'push');
            app.InputPositiveFilePath.ButtonPushedFcn = createCallbackFcn(app, @InputPositiveFilePathButtonPushed, true);
            app.InputPositiveFilePath.Text = 'Browse for positive input file path';
            app.InputNegativeFilePath = uibutton(app.uipanel8, 'push');
            app.InputNegativeFilePath.ButtonPushedFcn = createCallbackFcn(app, @InputNegativeFilePathButtonPushed, true);
            app.InputNegativeFilePath.Text = 'Browse for negative Input file path';
            app.OutputFilePath = uibutton(app.uipanel8, 'push');
            app.OutputFilePath.ButtonPushedFcn = createCallbackFcn(app, @OutputFilePathButtonPushed, true);
            app.OutputFilePath.Text = 'Browse for output file path';
            app.EditField_Meta = uieditfield(app.uipanel8, 'text');
            app.EditField_Meta.Editable = 'off';
            app.EditField_Pos = uieditfield(app.uipanel8, 'text');
            app.EditField_Pos.Editable = 'off';
            app.EditField_Neg = uieditfield(app.uipanel8, 'text');
            app.EditField_Neg.Editable = 'off';
            app.EditField_Out = uieditfield(app.uipanel8, 'text');
            app.EditField_Out.Editable = 'off';
            app.uipanel28 = uipanel(app.PreprocessingTab);
            app.uipanel28.Title = 'Parameter: NegPosMerging';
            app.text67 = uilabel(app.uipanel28);
            app.text67.Text = 'AnnotationsNames';
            app.AnnotationNamesEdit = uieditfield(app.uipanel28, 'text');
            app.AnnotationNamesEdit.Tooltip = {'Names of the annotation (the column names in the metadata file) to keep for merging the pos and neg batches'; ' they should have the same value in both polarities.'};
            app.AnnotationNamesEdit.Value = 'Batch,SampleType';
            app.text68 = uilabel(app.uipanel28);
            app.text68.Text = 'LabelsViewPCA';
            app.PCANPMLabelViewEdit = uieditfield(app.uipanel28, 'text');
            app.PCANPMLabelViewEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots. '};
            app.PCANPMLabelViewEdit.Value = 'SampleType,SampleType';
            app.Reset = uibutton(app.PreprocessingTab, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.Process = uibutton(app.PreprocessingTab, 'push');
            app.Process.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.Process.Text = 'Process';
            app.Lamp = uilamp(app.PreprocessingTab);
            app.ViewlogButton = uibutton(app.PreprocessingTab, 'push');
            app.ViewlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlogButtonPushed, true);
            app.ViewlogButton.Text = 'View log';
            app.NegativemodeTab = uitab(app.TabGroup);
            app.NegativemodeTab.Title = 'Negative mode';
            app.uipanel19 = uipanel(app.NegativemodeTab);
            app.uipanel19.Title = '3-DataRemover';
            app.nListOfNames = uieditfield(app.uipanel19, 'text');
            app.nListOfNames.Tooltip = {'Names of the samples which should be disregarded from the analysis'};
            app.text41 = uilabel(app.uipanel19);
            app.text41.Text = 'ListOfNames';
            app.uipanel21 = uipanel(app.NegativemodeTab);
            app.uipanel21.Title = '1-MetaTableParser';
            app.text46 = uilabel(app.uipanel21);
            app.text46.Text = 'Sheet';
            app.nSheetEdit = uieditfield(app.uipanel21, 'text');
            app.nSheetEdit.Tooltip = {'Name of the excel Sheet  of the metadata '};
            app.uipanel22 = uipanel(app.NegativemodeTab);
            app.uipanel22.Title = '4-BlankFilter';
            app.nBFLablesViewPCAEdit = uieditfield(app.uipanel22, 'text');
            app.nBFLablesViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots. '};
            app.nBFLablesViewPCAEdit.Value = 'SampleType,SampleType';
            app.text49 = uilabel(app.uipanel22);
            app.text49.Text = 'LabelsViewPCA';
            app.nBFLabelViewEdit = uieditfield(app.uipanel22, 'text');
            app.nBFLabelViewEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.nBFLabelViewEdit.Value = 'UserID';
            app.text50 = uilabel(app.uipanel22);
            app.text50.Text = 'LabelsViewPlots';
            app.nThresholdBlankEdit = uieditfield(app.uipanel22, 'text');
            app.nThresholdBlankEdit.Tooltip = {'Threshold between Intensity of a feature in the Blank compared to the feature in a QC or Sample'};
            app.nThresholdBlankEdit.Value = '3';
            app.text51 = uilabel(app.uipanel22);
            app.text51.Text = 'ThresholdRatio';
            app.uipanel23 = uipanel(app.NegativemodeTab);
            app.uipanel23.Title = '5-PercentageRuleFilter';
            app.nPRFLAbelsViewPCAEdit = uieditfield(app.uipanel23, 'text');
            app.nPRFLAbelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots. '};
            app.nPRFLAbelsViewPCAEdit.Value = 'SampleType, SampleType';
            app.text52 = uilabel(app.uipanel23);
            app.text52.Text = 'LabelsViewPCA';
            app.nPRFLAbelsViewPlotsEdit = uieditfield(app.uipanel23, 'text');
            app.nPRFLAbelsViewPlotsEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.nPRFLAbelsViewPlotsEdit.Value = 'SampleType';
            app.text53 = uilabel(app.uipanel23);
            app.text53.Text = 'LabelsViewPlots';
            app.nGroupListEdit = uieditfield(app.uipanel23, 'text');
            app.nGroupListEdit.Tooltip = {'The groups on which the filtering should occur (e.g.:  SampleType:QC'; ' Conditions:Healthy'; ' Conditions: Infected)'};
            app.text54 = uilabel(app.uipanel23);
            app.text54.Text = 'GroupList';
            app.nThresholdPercRuleFilterEdit = uieditfield(app.uipanel23, 'text');
            app.nThresholdPercRuleFilterEdit.Tooltip = {'Percentage of features required in each sample group'};
            app.nThresholdPercRuleFilterEdit.Value = '0.8';
            app.text55 = uilabel(app.uipanel23);
            app.text55.Text = 'Threshold';
            app.text56 = uilabel(app.uipanel23);
            app.text56.Text = 'MethodOfFiltering';
            app.nMethodOfFilteringEdit = uieditfield(app.uipanel23, 'text');
            app.nMethodOfFilteringEdit.Tooltip = {'The filtering should occur only on the QC'; ' on the QC and the samples or on the QC or the samples. (values= QcOnly'; ' QcAndSample'; ' QcOrSample)'};
            app.uipanel24 = uipanel(app.NegativemodeTab);
            app.uipanel24.Title = '6-QcCV';
            app.nQcCVLabelsViewPCAEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVLabelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots. '};
            app.nQcCVLabelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text57 = uilabel(app.uipanel24);
            app.text57.Text = 'LabelsViewPCA';
            app.nQcCVLabelsViewPlotsEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVLabelsViewPlotsEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.nQcCVLabelsViewPlotsEdit.Value = 'SampleType';
            app.text58 = uilabel(app.uipanel24);
            app.text58.Text = 'LabelsViewPlots';
            app.nQcCVLoqEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVLoqEdit.Tooltip = {'Limit of Quantitation'};
            app.nQcCVLoqEdit.Value = '1e4';
            app.text59 = uilabel(app.uipanel24);
            app.text59.Text = 'LoQ';
            app.nQcCVThresholdEdit = uieditfield(app.uipanel24, 'text');
            app.nQcCVThresholdEdit.Tooltip = {'The coefficiant of variation (cv ) allowed for the features present in the QC.'};
            app.nQcCVThresholdEdit.Value = '0.3';
            app.text60 = uilabel(app.uipanel24);
            app.text60.Text = 'Threshold';
            app.uipanel25 = uipanel(app.NegativemodeTab);
            app.uipanel25.Title = '7-IntraDriftCorrector';
            app.text63 = uilabel(app.uipanel25);
            app.text63.Text = 'LabelsViewPCA';
            app.nIDFLAbelsViewPCAEdit = uieditfield(app.uipanel25, 'text');
            app.nIDFLAbelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots. '};
            app.nIDFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.uipanel26 = uipanel(app.NegativemodeTab);
            app.uipanel26.Title = '8-InterDriftCorrector';
            app.nIaDFLAbelsViewPCAEdit = uieditfield(app.uipanel26, 'text');
            app.nIaDFLAbelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots. '};
            app.nIaDFLAbelsViewPCAEdit.Value = 'SampleType, SampleType';
            app.text64 = uilabel(app.uipanel26);
            app.text64.Text = 'LabelsViewPCA';
            app.uipanel20 = uipanel(app.NegativemodeTab);
            app.uipanel20.Title = '2-ConsensusDataAnnotator';
            app.text42 = uilabel(app.uipanel20);
            app.text42.Text = 'HeadersToUse';
            app.nHeaderToUseEdit = uieditfield(app.uipanel20, 'text');
            app.nHeaderToUseEdit.Tooltip = {'The column names that should be used to annotate the samples'};
            app.nHeaderToUseEdit.Value = 'SequenceNumber,Batch,Polarity,UserID,SampleType';
            app.text43 = uilabel(app.uipanel20);
            app.text43.Text = 'UserIdHeader';
            app.nUserIDHeaderEdit = uieditfield(app.uipanel20, 'text');
            app.nUserIDHeaderEdit.Tooltip = {'The column name indicating the raw names of the samples'};
            app.text44 = uilabel(app.uipanel20);
            app.text44.Text = 'LabelsViewPlots';
            app.nCDALabelViewEdit = uieditfield(app.uipanel20, 'text');
            app.nCDALabelViewEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.nCDALabelViewEdit.Value = 'UserID';
            app.text45 = uilabel(app.uipanel20);
            app.text45.Text = 'LabelsViewPCA';
            app.nCDALabelsViewPCAEdit = uieditfield(app.uipanel20, 'text');
            app.nCDALabelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.nCDALabelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.ActivateCheckBoxInterDriftNeg = uicheckbox(app.NegativemodeTab);
            app.ActivateCheckBoxInterDriftNeg.Text = 'Activate';
            app.ActivateCheckBoxInterDriftNeg.Value = true;
            app.ActivateCheckBoxIntraDriftNeg = uicheckbox(app.NegativemodeTab);
            app.ActivateCheckBoxIntraDriftNeg.Text = 'Activate';
            app.ActivateCheckBoxIntraDriftNeg.Value = true;
            app.ActivateCheckBoxQcCVNeg = uicheckbox(app.NegativemodeTab);
            app.ActivateCheckBoxQcCVNeg.Text = 'Activate';
            app.ActivateCheckBoxQcCVNeg.Value = true;
            app.ActivateCheckBoxPercentageFilterNeg = uicheckbox(app.NegativemodeTab);
            app.ActivateCheckBoxPercentageFilterNeg.Text = 'Activate';
            app.ActivateCheckBoxPercentageFilterNeg.Value = true;
            app.ActivateCheckBoxBlankFilterNeg = uicheckbox(app.NegativemodeTab);
            app.ActivateCheckBoxBlankFilterNeg.Text = 'Activate';
            app.ActivateCheckBoxBlankFilterNeg.Value = true;
            app.PositivemodeTab = uitab(app.TabGroup);
            app.PositivemodeTab.Title = 'Positive mode';
            app.uipanel29 = uipanel(app.PositivemodeTab);
            app.uipanel29.Title = '3-DataRemover';
            app.pListOfNames = uieditfield(app.uipanel29, 'text');
            app.pListOfNames.Tooltip = {'Names of the samples which should be disregarded from the analysis'};
            app.text69 = uilabel(app.uipanel29);
            app.text69.Text = 'ListOfNames';
            app.uipanel30 = uipanel(app.PositivemodeTab);
            app.uipanel30.Title = '2-ConsensusDataAnnotator';
            app.text70 = uilabel(app.uipanel30);
            app.text70.Text = 'HeadersToUse';
            app.pHeaderToUseEdit = uieditfield(app.uipanel30, 'text');
            app.pHeaderToUseEdit.Tooltip = {'The column names that should be used to annotate the samples'};
            app.pHeaderToUseEdit.Value = 'SequenceNumber,Batch,Polarity,UserID,SampleType';
            app.text71 = uilabel(app.uipanel30);
            app.text71.Text = 'UserIdHeader';
            app.pUserIDHeaderEdit = uieditfield(app.uipanel30, 'text');
            app.pUserIDHeaderEdit.Tooltip = {'The column name indicating the raw names of the samples'};
            app.text72 = uilabel(app.uipanel30);
            app.text72.Text = 'LabelsViewPlots';
            app.pCDALabelViewEdit = uieditfield(app.uipanel30, 'text');
            app.pCDALabelViewEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.pCDALabelViewEdit.Value = 'UserID';
            app.text73 = uilabel(app.uipanel30);
            app.text73.Text = 'LabelsViewPCA';
            app.pCDALabelsViewPCAEdit = uieditfield(app.uipanel30, 'text');
            app.pCDALabelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.pCDALabelsViewPCAEdit.Value = 'SampleType, SampleType';
            app.uipanel31 = uipanel(app.PositivemodeTab);
            app.uipanel31.Title = '1-MetaTableParser';
            app.text74 = uilabel(app.uipanel31);
            app.text74.Text = 'Sheet';
            app.pSheetEdit = uieditfield(app.uipanel31, 'text');
            app.pSheetEdit.Tooltip = {'Name of the excel Sheet  of the metadata '};
            app.uipanel32 = uipanel(app.PositivemodeTab);
            app.uipanel32.Title = '4-BlankFilter';
            app.pBFLablesViewPCAEdit = uieditfield(app.uipanel32, 'text');
            app.pBFLablesViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.pBFLablesViewPCAEdit.Value = 'SampleType, SampleType';
            app.text75 = uilabel(app.uipanel32);
            app.text75.Text = 'LabelsViewPCA';
            app.pBFLabelViewEdit = uieditfield(app.uipanel32, 'text');
            app.pBFLabelViewEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.pBFLabelViewEdit.Value = 'UserID';
            app.text76 = uilabel(app.uipanel32);
            app.text76.Text = 'LabelsViewPlots';
            app.pThresholdBlankEdit = uieditfield(app.uipanel32, 'text');
            app.pThresholdBlankEdit.Tooltip = {'Threshold between Intensity of a feature in the Blank compared to the feature in a QC or Sample'};
            app.pThresholdBlankEdit.Value = '3';
            app.text77 = uilabel(app.uipanel32);
            app.text77.Text = 'ThresholdRatio';
            app.uipanel33 = uipanel(app.PositivemodeTab);
            app.uipanel33.Title = '5-PercentageRuleFilter';
            app.pPRFLAbelsViewPCAEdit = uieditfield(app.uipanel33, 'text');
            app.pPRFLAbelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.pPRFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text78 = uilabel(app.uipanel33);
            app.text78.Text = 'LabelsViewPCA';
            app.pPRFLAbelsViewPlotsEdit = uieditfield(app.uipanel33, 'text');
            app.pPRFLAbelsViewPlotsEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.pPRFLAbelsViewPlotsEdit.Value = 'SampleType';
            app.text79 = uilabel(app.uipanel33);
            app.text79.Text = 'LabelsViewPlots';
            app.pGroupListEdit = uieditfield(app.uipanel33, 'text');
            app.pGroupListEdit.Tooltip = {'The groups on which the filtering should occur (e.g.:  SampleType:QC'; ' Conditions:Healthy'; ' Conditions: Infected)'};
            app.text80 = uilabel(app.uipanel33);
            app.text80.Text = 'GroupList';
            app.pThresholdPercRuleFilterEdit = uieditfield(app.uipanel33, 'text');
            app.pThresholdPercRuleFilterEdit.Tooltip = {'Percentage of features required in each sample group'};
            app.pThresholdPercRuleFilterEdit.Value = '0.8';
            app.text81 = uilabel(app.uipanel33);
            app.text81.Text = 'Threshold';
            app.text82 = uilabel(app.uipanel33);
            app.text82.Text = 'MethodOfFiltering';
            app.pMethodOfFilteringEdit = uieditfield(app.uipanel33, 'text');
            app.pMethodOfFilteringEdit.Tooltip = {'The filtering should occur only on the QC'; ' on the QC and the samples or on the QC or the samples. (values= QcOnly'; ' QcAndSample'; ' QcOrSample)'};
            app.uipanel34 = uipanel(app.PositivemodeTab);
            app.uipanel34.Title = '6-QcCV';
            app.pQcCVLabelsViewPCAEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVLabelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.pQcCVLabelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text83 = uilabel(app.uipanel34);
            app.text83.Text = 'LabelsViewPCA';
            app.pQcCVLabelsViewPlotsEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVLabelsViewPlotsEdit.Tooltip = {'The column name in the metadata which will give the labels for the graphs. '};
            app.pQcCVLabelsViewPlotsEdit.Value = 'SampleType';
            app.text84 = uilabel(app.uipanel34);
            app.text84.Text = 'LabelsViewPlots';
            app.pQcCVLoqEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVLoqEdit.Tooltip = {'Limit of Quantitation'};
            app.pQcCVLoqEdit.Value = '1e4';
            app.text85 = uilabel(app.uipanel34);
            app.text85.Text = 'LoQ';
            app.pQcCVThresholdEdit = uieditfield(app.uipanel34, 'text');
            app.pQcCVThresholdEdit.Tooltip = {'The coefficiant of variation (cv ) allowed for the features present in the QC.'};
            app.pQcCVThresholdEdit.Value = '0.3';
            app.text86 = uilabel(app.uipanel34);
            app.text86.Text = 'Threshold';
            app.uipanel35 = uipanel(app.PositivemodeTab);
            app.uipanel35.Title = '7-IntraDriftCorrector';
            app.text87 = uilabel(app.uipanel35);
            app.text87.Text = 'LabelsViewPCA';
            app.pIDFLAbelsViewPCAEdit = uieditfield(app.uipanel35, 'text');
            app.pIDFLAbelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.pIDFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.uipanel36 = uipanel(app.PositivemodeTab);
            app.uipanel36.Title = '8-InterDriftCorrector';
            app.pIaDFLAbelsViewPCAEdit = uieditfield(app.uipanel36, 'text');
            app.pIaDFLAbelsViewPCAEdit.Tooltip = {'The column name in the metadata which will give the labels of the PCA. The first one for the colour of the dots and the second one for the label of the dots.'};
            app.pIaDFLAbelsViewPCAEdit.Value = 'SampleType,SampleType';
            app.text88 = uilabel(app.uipanel36);
            app.text88.Text = 'LabelsViewPCA';
            app.ActivateCheckBoxInterDriftPos = uicheckbox(app.PositivemodeTab);
            app.ActivateCheckBoxInterDriftPos.Text = 'Activate';
            app.ActivateCheckBoxInterDriftPos.Value = true;
            app.ActivateCheckBoxIntraDriftPos = uicheckbox(app.PositivemodeTab);
            app.ActivateCheckBoxIntraDriftPos.Text = 'Activate';
            app.ActivateCheckBoxIntraDriftPos.Value = true;
            app.ActivateCheckBoxQcCVPos = uicheckbox(app.PositivemodeTab);
            app.ActivateCheckBoxQcCVPos.Text = 'Activate';
            app.ActivateCheckBoxQcCVPos.Value = true;
            app.ActivateCheckBoxPercentageFilterPos = uicheckbox(app.PositivemodeTab);
            app.ActivateCheckBoxPercentageFilterPos.Text = 'Activate';
            app.ActivateCheckBoxPercentageFilterPos.Value = true;
            app.ActivateCheckBoxBlankFilterPos = uicheckbox(app.PositivemodeTab);
            app.ActivateCheckBoxBlankFilterPos.Text = 'Activate';
            app.ActivateCheckBoxBlankFilterPos.Value = true;
            % Show the figure after all components are created
            app.PreprocessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreProcessing_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreprocessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingUIFigure)
        end
    end
end