classdef mzConvert_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        uipanel4                 matlab.ui.container.Panel
        text9                    matlab.ui.control.Label
        IntenistyThresholdEdit   matlab.ui.control.EditField
        text10                   matlab.ui.control.Label
        ScanRangeEdit            matlab.ui.control.EditField
        text11                   matlab.ui.control.Label
        TimeRangeEdit            matlab.ui.control.EditField
        text12                   matlab.ui.control.Label
        MzRangeEdit              matlab.ui.control.EditField
        Convert                  matlab.ui.control.Button
        Reset                    matlab.ui.control.Button
        text13                   matlab.ui.control.Label
        text14                   matlab.ui.control.Label
        FileNameFilterEdit       matlab.ui.control.EditField
        FileExtensionFilterEdit  matlab.ui.control.EditField
        Lamp                     matlab.ui.control.Lamp
        ViewlogButton            matlab.ui.control.Button
        text13_2                 matlab.ui.control.Label
        OutputFormatEdit         matlab.ui.control.EditField
        uipanel5                 matlab.ui.container.Panel
        BrowseInput              matlab.ui.control.Button
        BrowseOutput             matlab.ui.control.Button
        InputPath                matlab.ui.control.EditField
        OutputPath               matlab.ui.control.EditField
        uipanel6                 matlab.ui.container.Panel
        text15                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mzConvert_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to mzConvert (see VARARGIN)
            %
            %             % Choose default command line output for mzConvert
            %
            %             app.Convert = uibutton;
            app.Convert.Tooltip= 'Convert Raw Files using Proteowizard';
            app.FileExtensionFilterEdit.Tooltip= 'The extension of your raw data, e.g.: raw, d ';
            app.FileNameFilterEdit.Tooltip= ' Filter to take the raw files with a pattern  ';
            app.MzRangeEdit.Tooltip = 'A filter to extract a mz range, e.g. 600,900';
            app.TimeRangeEdit.Tooltip = 'A filter for the time range in seconds to extract, e.g.: 500,900';
            app.IntenistyThresholdEdit.Tooltip = 'The Threshhold for the intensities ';
            app.ScanRangeEdit.Tooltip = 'A filter for the scan range e.g.: 200,300';


        end

        % Button pushed function: BrowseInput
        function BrowseInput_Callback(app, event)
            % hObject    handle to BrowseInput (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % inFolder = uigetdir();
            inFolder = uipickfiles ;
            if ~isempty(inFolder) && ~isnumeric(inFolder)
                app.InputPath.Value=char(join(inFolder, ','));
                setappdata(0,'inputdatapath', inFolder);
            end
        end

        % Button pushed function: BrowseOutput
        function BrowseOutput_Callback(app, event)
            % hObject    handle to BrowseOutput (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outFolder = uigetdir();
            if ~isempty(outFolder) && ~isnumeric(outFolder)
                app.OutputPath.Value=char(outFolder);
                setappdata(0,'outputdatapath', outFolder);
            end
        end

        % Button pushed function: Convert
        function Convert_Callback(app, event)
            % hObject    handle to Convert (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.Lamp.Color= 'red';
          
            diary(fullfile(getappdata(0, 'outputdatapath'), '/log.txt'));
            datetime.setDefaultFormats('default','yyyyMMdd_hhmm')
            
            %% MzConvert Controller
            ctrl = biotracs.mzconvert.controller.Controller();
            
            workflow = ctrl.get('MzConvertWorkflow');
            workflow.getConfig()...
                .updateParamValue( 'WorkingDirectory', getappdata(0, 'outputdatapath'));
            
            fileExtensionFilterValue = app.FileExtensionFilterEdit.Value;
            
            fileNameFilterValue = app.FileNameFilterEdit.Value;
            
            inputAdpter = workflow.getNode('MzFileImporter');
            files = getappdata(0, 'inputdatapath');
            for i= 1:length(files)
                inputAdpter.addInputFilePath(files{i});
            end
            inputAdpter.getConfig()...
                .updateParamValue( 'FileExtensionFilter', fileExtensionFilterValue)...
                .updateParamValue('FileNameFilter', fileNameFilterValue);
            
            intensityThreshold = app.IntenistyThresholdEdit.Value;
            intensityThreshold = str2num(intensityThreshold);
            
            mzRangeValue= app.MzRangeEdit.Value;
            mzRangeValue = str2num(mzRangeValue);
            
            timeRangeValue= app.TimeRangeEdit.Value;
            timeRangeValue= str2num(timeRangeValue);
            
            scanRangeValue =app.ScanRangeEdit.Value;
            scanRangeValue = str2num(scanRangeValue);
            
            outputFormat = app.OutputFormatEdit.Value;
            
            mzConvert = workflow.getNode('MzConverter');
            mzConvert.getConfig()...
                .updateParamValue('IntensityThreshold', intensityThreshold)...
                .updateParamValue('MzRange', mzRangeValue )...
                .updateParamValue('TimeRange', timeRangeValue)...
                .updateParamValue('OutputFormat', outputFormat)...
                .updateParamValue('ScanRange', scanRangeValue);
            
            workflow.run();
            if workflow.isEnded
                app.Lamp.Color= 'green';
            end
            
           
            diary off;
        end

        % Callback function
        function help_Callback(app, event)
            % hObject    handle to help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mzConvertHelp
        end

        % Callback function
        function setFolderInput(app, event)
            
        end

        % Button pushed function: ViewlogButton
        function ViewlogButtonPushed(app, event)
            outdir = getappdata(0, 'outputdatapath');
            system(['notepad ' fullfile(outdir,'/log.txt')])

        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            close(gcbf) 
            mzConvert_App;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'mzConvert';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Parameter';
            app.text9 = uilabel(app.uipanel4);
            app.text9.Text = 'IntensityThreshold';
            app.IntenistyThresholdEdit = uieditfield(app.uipanel4, 'text');
            app.IntenistyThresholdEdit.Value = '100';
            app.text10 = uilabel(app.uipanel4);
            app.text10.Text = 'ScanRange';
            app.ScanRangeEdit = uieditfield(app.uipanel4, 'text');
            app.text11 = uilabel(app.uipanel4);
            app.text11.Text = 'TimeRange';
            app.TimeRangeEdit = uieditfield(app.uipanel4, 'text');
            app.text12 = uilabel(app.uipanel4);
            app.text12.Text = 'MzRange';
            app.MzRangeEdit = uieditfield(app.uipanel4, 'text');
            app.Convert = uibutton(app.uipanel4, 'push');
            app.Convert.ButtonPushedFcn = createCallbackFcn(app, @Convert_Callback, true);
            app.Convert.Text = 'Convert';
            app.Reset = uibutton(app.uipanel4, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Text = 'FileExtensionFilter';
            app.text14 = uilabel(app.uipanel4);
            app.text14.Text = 'FileNameFilter';
            app.FileNameFilterEdit = uieditfield(app.uipanel4, 'text');
            app.FileExtensionFilterEdit = uieditfield(app.uipanel4, 'text');
            app.FileExtensionFilterEdit.Value = 'raw';
            app.Lamp = uilamp(app.uipanel4);
            app.ViewlogButton = uibutton(app.uipanel4, 'push');
            app.ViewlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlogButtonPushed, true);
            app.ViewlogButton.Text = 'View log';
            app.text13_2 = uilabel(app.uipanel4);
            app.text13_2.Text = 'OutputFormat';
            app.OutputFormatEdit = uieditfield(app.uipanel4, 'text');
            app.OutputFormatEdit.Tooltip = {'Extension of the output format'};
            app.OutputFormatEdit.Value = 'mzXML';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Input/output files';
            app.BrowseInput = uibutton(app.uipanel5, 'push');
            app.BrowseInput.ButtonPushedFcn = createCallbackFcn(app, @BrowseInput_Callback, true);
            app.BrowseInput.Text = 'Browse for input file path';
            app.BrowseOutput = uibutton(app.uipanel5, 'push');
            app.BrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutput_Callback, true);
            app.BrowseOutput.Text = 'Browse for output file path';
            app.InputPath = uieditfield(app.uipanel5, 'text');
            app.InputPath.Editable = 'off';
            app.OutputPath = uieditfield(app.uipanel5, 'text');
            app.OutputPath.Editable = 'off';
            app.uipanel6 = uipanel(app.figure1);
            app.text15 = uilabel(app.uipanel6);
            app.text15.Text = 'Raw files conversion';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzConvert_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @mzConvert_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end