classdef popup_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        Step1           matlab.ui.control.Button
        Step2           matlab.ui.control.Button
        Step3           matlab.ui.control.Button
        Step4           matlab.ui.control.Button
        Image           matlab.ui.control.Image
        Image2          matlab.ui.control.Image
        Image3          matlab.ui.control.Image
        Image4          matlab.ui.control.Image
        Image5          matlab.ui.control.Image
        Identification  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function edit1_CreateFcn(~, hObject, ~, ~)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function popup_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to popup (see VARARGIN)
%             
%             % Choose default command line output for popup
%             app.output = hObject;
%             matlabImage = imread('./img/background.jpeg');
%             image( matlabImage)
%             axis off
%             axis image
%             %%
%             
%             % Update handles structure
%             guidata(hObject, handles);

        end

        % Button pushed function: Identification
        function LipidMatch_Callback(app, event)
            % hObject    handle to LipidMatch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            LipidMatch_App
        end

        % Button pushed function: Step1
        function Step1_Callback(app, event)
            % hObject    handle to Step1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mzConvert_App
        end

        % Button pushed function: Step2
        function Step2_Callback(app, event)
            % hObject    handle to Step2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FeatureExtraction_App
        end

        % Button pushed function: Step3
        function Step3_Callback(app, event)
            % hObject    handle to Step3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            FeatureLinking_App
        end

        % Button pushed function: Step4
        function Step4_Callback(app, event)
            % hObject    handle to Step4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PreProcessing_App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'popup';
            app.Step1 = uibutton(app.figure1, 'push');
            app.Step1.ButtonPushedFcn = createCallbackFcn(app, @Step1_Callback, true);
            app.Step1.Icon = 'B1.png';
            app.Step1.Text = '';
            app.Step2 = uibutton(app.figure1, 'push');
            app.Step2.ButtonPushedFcn = createCallbackFcn(app, @Step2_Callback, true);
            app.Step2.Icon = 'B2.png';
            app.Step2.Text = '';
            app.Step3 = uibutton(app.figure1, 'push');
            app.Step3.ButtonPushedFcn = createCallbackFcn(app, @Step3_Callback, true);
            app.Step3.Icon = 'B3.png';
            app.Step3.Text = '';
            app.Step4 = uibutton(app.figure1, 'push');
            app.Step4.ButtonPushedFcn = createCallbackFcn(app, @Step4_Callback, true);
            app.Step4.Icon = 'B4.png';
            app.Step4.Text = '';
            app.Identification = uibutton(app.figure1, 'push');
            app.Identification.ButtonPushedFcn = createCallbackFcn(app, @LipidMatch_Callback, true);
            app.Identification.Icon = 'B5.png';
            app.Identification.Text = '';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'BioTracs-Mimosas-Home-OK-03.jpg';
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = 'mzconvert.png';
            app.Image3 = uiimage(app.figure1);
            app.Image3.ImageSource = 'FeatureAnnotation.png';
            app.Image4 = uiimage(app.figure1);
            app.Image4.ImageSource = 'PreProcessing.png';
            app.Image5 = uiimage(app.figure1);
            app.Image5.ImageSource = 'FeatureIdentification.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popup_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @popup_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end